classdef TFAP_2pt0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PreprocessDataMenu           matlab.ui.container.Menu
        LoadRawDataMenu              matlab.ui.container.Menu
        RemoveChannelsfromFileMenu   matlab.ui.container.Menu
        EpochDataMenu                matlab.ui.container.Menu
        BaselineDataMenu             matlab.ui.container.Menu
        ArtifactRejectDataMenu       matlab.ui.container.Menu
        EyeMovementRejectionMenu     matlab.ui.container.Menu
        ResampleDataMenu             matlab.ui.container.Menu
        ReferenceDataMenu            matlab.ui.container.Menu
        AverageDataMenu              matlab.ui.container.Menu
        FrequencyAnalysisMenu        matlab.ui.container.Menu
        FilterDataMenu               matlab.ui.container.Menu
        FourierTransformMenu         matlab.ui.container.Menu
        WaveletTransformMenu         matlab.ui.container.Menu
        CrossElectrodeCoherenceMenu  matlab.ui.container.Menu
        OperationsMenu               matlab.ui.container.Menu
        AddDataMenu                  matlab.ui.container.Menu
        SubtractDataMenu             matlab.ui.container.Menu
        ExportMenu                   matlab.ui.container.Menu
        PlottingMenu                 matlab.ui.container.Menu
        PlotTimeDataMenu             matlab.ui.container.Menu
        PlotFFTDataMenu              matlab.ui.container.Menu
        PlottimeFrequencyDataMenu    matlab.ui.container.Menu
        BatchAnalysisMenu            matlab.ui.container.Menu
        text1                        matlab.ui.control.Label
        text3                        matlab.ui.control.Label
        text4                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        
        %variables encoding trigger values and labels
        triggers = [];
        triggerLabels = {};
        
        %variables holding parameters of the epoch
        epochStart = nan;
        epochEnd = nan;
        FS = nan;
        lengthEpoch = nan;
        epochTimeVec = nan;
        epochOnTrigger = nan;
        
        %artifact rejection parameters
        artRej_Threshold = nan;
        
        numChannels = nan;
        channelLabels = nan;
    end
    
    methods (Access = public)
        
        function results = readTriggerFile(app)
            
            [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Trigger Labels', 'multiselect','off');
            if FilterIndex == 0
            else
                try
                    curFile = [PathName FileName];
                    fid = fopen(curFile);
                    triggers = [];
                    triggerLabels = {};
                    while ~feof(fid)
                        tempTr = []; tempTrLab = [];
                        tline = fgetl(fid);
                        a = findstr(tline,'"');
                        tempTr = str2num(tline(1:a(1)-1));
                        tempTrLab = (tline(a(1)+1:a(2)-1));
                        if isnumeric (tempTr) & ~isempty(tempTr)
                            triggers = cat(1,triggers,tempTr);
                            triggerLabels = cat(1,triggerLabels,tempTrLab);
                        else
                        end
                    end
                catch
                    opts = struct('WindowStyle','modal',...
                        'Interpreter','tex');
                    f = errordlg('Error Reading Trigger File',...
                        'Triggers Not Loaded', opts);
                    if exist('fid', 'var') == 1
                        fclose(fid);
                    else
                    end
                end
            end
            
        end
        
        function [data,numChan,labels,txt,fs, triggersSeq] = readBDF_file(app,filename)
            try
                [data,numChan,labels,txt,fs] = eeg_read_bdf(filename,'all','n');
                triggersSeq = ft_read_event(filename);
                
            catch
                errordlg('Error reading File');
            end
            
        end


        function epochData(app,filename,saveData)
            
            %loading data
            theData = [];
            try
                [data,numChan,labels,txt,fs, triggersSeq] = readBDF_file(app,filename);
                
                app.numChannels = numChan;
                app.channelLabels = labels;
                app.FS = fs;
                
                %epoching data
                %timing information of epoch
                startPt = (round(app.epochStart*app.FS));
                endPt = abs(round(app.epochEnd*app.FS));
                %length of epoch
                lengthEpoch = round(endPt + abs(startPt));
                %determining the number of epochs (based on epoch length and size of data)
                numEpochs = floor(size(data,2)/lengthEpoch);
                theData = zeros(numChan,lengthEpoch,numEpochs);
                
                %data = data(:,offSetTime:end);
                if (app.lengthEpoch + abs(startPt)) < size(data,2)
                    
                    zeroPoint = abs(startPt) + 1;
                    for nn = 1 : numEpochs
                        
                        dataTemp = data(:,(zeroPoint + startPt): (zeroPoint + endPt));
                        if size(dataTemp,2) > lengthEpoch
                            dataTemp(:,lengthEpoch+1:end) = [];
                        else
                        end
                        
                        theData(:,:,nn) = dataTemp;
                    end
                    
                    if saveData == 1
                        epochStart = app.epochStart;
                        epochEnd = app.epochEnd;
                        FS = app.FS;
                        
                        [fileOut,pathOut] = uiputfile('.mat');
                        
                        foi = exist ('freqVec', 'var');
                        if foi == 1
                        else
                            freqVec = [];
                        end
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        
                        msgbox('Epoched Trials Saved');
                    else
                    end
                    
                else
                    errordlg('Error epoching data');
                    return
                end
                
            catch
                errordlg('Error epoching data');
                return
            end
        
        end
        
        function fftDataAnalysis(app,PathName,FileName)
            
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                for F = 1 : numFiles %Go through each file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
                    
                    %loading the file
                    disp(['FFT Analysis on: ' curFile]);
                    load(curFile);
                    app.FS = FS;
                    dimData = ndims(theData);
                    numEpoch = [];
                    %determining whether data is single trial or averaged
                    if dimData < 3
                        fftData = zeros(size(theData,1),size(theData,2));
                        numEpoch = 1;
                    else
                        fftData = zeros(size(theData,1),size(theData,2),size(theData,3));
                        numEpoch = size(theData,3);
                    end
                    
                    for n = 1 : numEpoch
                        curData = [];
                                                
                        if numEpoch == 1
                            curData = theData;
                        else
                            curData = theData(:,:,n);
                        end
                        
                        curData = fft(curData);
                        lengthData = size(curData,2);
                        P2 = abs(curData/lengthData);
                        P1 = P2(1:lengthData/2+1);
                        P1(2:end-1) = 2*P1(2:end-1);
                        curData = P1;
                        
                        if numEpoch == 1
                            fftData = curData;
                        else                            
                            fftData(:,:,n) = curData;
                        end
                        
                        if F == 1 & n == 1
                            FreqVec = FS*(0:(lengthData/2))/lengthData;
                        else
                        end
                    end
                    
                    theData = fftData;
                    %saving the file
                    [fileOut,pathOut] = uiputfile('.mat');
                    if fileOut ~=0
                        FS = app.FS;
                        
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochE == 1
                        else
                            epochEnd = [];
                        end                        
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        
                        msgbox('FFT Trials Saved');
                    else
                    end
                end
                
            catch
                errordlg('Error Computing FFT');
                return
            end
            
            
        end
    
        function waveletTransform(app,PathName,FileName, foi,waveletwidth)
             try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                for F = 1 : numFiles %Go through each file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
                    
                    %loading the file
                    disp(['Wavelet Analysis on: ' curFile]);
                    load(curFile);
                    app.FS = FS;
                    dimData = ndims(theData);
                    numEpoch = [];
                    %determining whether data is single trial or averaged
                    if dimData < 3
                        WavData_power = zeros(size(theData,1),size(theData,2), length(foi));
                        WavData_ITC = WavData_power;
                        numEpoch = 1;
                    else
                        WavData_power = zeros(size(theData,1),size(theData,2),length(foi),size(theData,3));
                        WavData_ITC = WavData_power;
                        numEpoch = size(theData,3);
                    end
                    
                    for n = 1 : numEpoch
                        curData = [];
                                                
                        if numEpoch == 1
                            curData = theData;
                        else
                            curData = theData(:,:,n);
                        end
                            
                        if numEpoch == 1
                            WavData_power = WLT;
                            WavData_ITC = WLTp;
                        else
                            WavData_power(:,:,:,n) = WLT;
                            WavData_ITC(:,:,:,n) = WLTp;
                        end
                        
                    end
                    
                    theData = WavData_power;
                    theData_ITC = WavData_ITC;
                    %saving the file
                    [fileOut,pathOut] = uiputfile('.mat');
                    if fileOut ~=0
                        FS = app.FS;
                        freqVec = foi;       
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochS == 1
                        else
                            epochEnd = [];
                        end
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        msgbox('Wavelet Transforms Saved');
                    else
                    end
                end
                
            catch
                errordlg('Error Computing Wavelet Transforms');
                return
            end
        end
        
        function avgDataFile(app,PathName,FileName,typeData)
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                allFS = [];
                for F = 1 : numFiles %Going through each selected file for art rej
                    
                    %loading the correct file
                    if numFiles == 1
                        theFile = FileName;
                    else
                        theFile = FileName{F};
                    end
                    disp(['Averaging ... Loading File: ' curFile]);
                    load([PathName theFile]);
                    allFS = cat(1,allFS,FS);
                    
                    if length(unique(allFS)) > 1
                        errordlg('Error in Averaging Data. Issues with Sampling Rate');
                        return
                    else
                    end
                    
                    if F == 1
                        count = 0;
                        dims = ndims(theData);
                        if typeData < 3
                            avgData = zeros(size(theData,1),size(theData,2));
                        else
                            avgData = zeros(size(theData,1),size(theData,2),size(theData,3));
                        end
                    else
                    end
                    
                    if typeData < 3
                        
                        if dims == 2 %grp averaging
                            avgData = avgData + theData;
                            count = count + 1;
                        else
                            for nn = 1 : size(theData,3)
                                avgData = avgData + theData(:,:,nn);
                                count = count + 1;
                            end
                        end
                    elseif typeData == 3
                        if dims == 3 %grp averaging
                            avgData = avgData + theData;
                            count = count + 1;
                        else
                            for nn = 1 : size(theData,4)
                                avgData = avgData + theData(:,:,:,nn);
                                count = count + 1;
                            end
                        end
                    else
                    end
                    
                end
                
                %averaging data
                theData = avgData ./ count;
                [fileOut,pathOut] = uiputfile('.mat');
                if fileOut ~= 0
                    FS = app.FS;
                    
                    epochS = exist ('epochStart', 'var');
                    if epochS == 1
                    else
                        epochStart = [];
                    end
                    epochE = exist ('epochEnd', 'var');
                    if epochE == 1
                    else
                        epochEnd = [];
                    end
                    foi = exist ('freqVec', 'var');
                    if foi == 1
                    else
                        freqVec = [];
                    end
                    itc = exist ('theData_ITC', 'var');
                    if itc == 1
                    else
                        theData_ITC = [];
                    end
                    
                    save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
               
                    msgbox('Averaged data Saved');
                else
                end
                
            catch
                errordlg('Error in Averaging Data');
                return
            end
            
        end
        
        function reReferenceData(app,PathName,FileName,refCh)
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                for F = 1 : numFiles %Go through each file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
                    
                    %loading the file
                    disp(['Re-referencing data ... : ' curFile]);
                    load(curFile);
                    app.FS = FS;
                    
                    %re-referencing data
                    refData = repmat(mean(theData(refCh,:,:),1,'omitnan'),size(theData,1),1,1);
                    
                    theData = theData - refData;
                    
                    %saving the file
                    [fileOut,pathOut] = uiputfile('.mat');
                    FS = app.FS;
                    if fileOut ~=0
                        
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochE == 1
                        else
                            epochEnd = [];
                        end
                        foi = exist ('freqVec', 'var');
                        if foi == 1
                        else
                            freqVec = [];
                        end
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        
                        msgbox('Re-Referenced Trials Saved');
                    else
                    end
                end
                
            catch
                errordlg('Error Re-referencing Data');
                return
            end
        end
        
        function artRej_criteria(app,PathName,FileName, answer)
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                app.artRej_Threshold = str2num(answer{1});
                
                
                for F = 1 : numFiles %Going through each selected file for art rej
                    
                    %variable holding which trials in each file are rejected
                    artRejTrials_perfile = 0;
                    
                    %loading the correct file
                    if numFiles == 1
                        theFile = FileName;
                    else
                        theFile = FileName{F};
                    end
                    disp(['Artifact Rejection File: ' curFile]);
                    load([PathName theFile]);
                    app.FS = FS;
                    
                    %determining which trials have data points outside the accepted
                    %window
                    trialData = []; delTrials = [];
                    p = 1;
                    trialData = theData;
                    for n = 1 : size(trialData,3)
                        tempTrial = trialData(:,:,n);
                        if find(abs(tempTrial(:)) > app.artRej_Threshold)
                            delTrials = cat(1,delTrials,n);
                            artRejTrials_perfile = artRejTrials_perfile + 1;
                        else
                        end
                    end
                    %remove trials
                    if artRejTrials_perfile ~= 0
                        trialData(:,:,artRejTrials_perfile) = [];
                    else
                    end
                    theData = trialData;
                    
                    
                    [fileOut,pathOut] = uiputfile('.mat');
                    FS = app.FS;
                    if fileOut ~= 0
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochE == 1
                        else
                            epochEnd = [];
                        end
                        foi = exist ('freqVec', 'var');
                        if foi == 1
                        else
                            freqVec = [];
                        end
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        
                        msgbox('Artifact Rejected Trials Saved');
                    else
                    end
                    
                    trialData = [];
                end
                
            catch
                errordlg('Error in Artifact Rejecting Trials');
                return
            end
            
        end
        
        function baselineData(app,PathName,FileName, startBase,endBase)
            %Baseline correction function
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                for F = 1 : numFiles %Go through each file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
                    
                    %loading the file
                    disp(['Baselining Data in File: ' curFile]);
                    load(curFile);
                    app.FS = FS;
                    
                    %ensuring baseline points are within the epoch
                    if startBase >= epochStart
                    else
                        errordlg('Baseline start value less than epoch start');
                        return
                    end
                    if endBase <= epochEnd
                    else
                        errordlg('Baseline start value less than epoch start');
                        return
                    end
                    %getting the correct sample points
                    pt1 = []; pt2 = []; FS = app.FS;
                    timeVec = epochStart:1/FS:epochEnd;
                    [aa,pt1] = min(abs(timeVec - startBase));
                    [aa,pt2] = min(abs(timeVec - endBase));
                    
                    %baselining data
                    theData = theData - repmat(mean(theData(:,pt1:pt2,:),2),[1,size(theData,2),1]);
                    
                    %saving the file
                    [fileOut,pathOut] = uiputfile('.mat');
                    if fileOut ~=0
                        FS = app.FS;
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochE == 1
                        else
                            epochEnd = [];
                        end
                        foi = exist ('freqVec', 'var');
                        if foi == 1
                        else
                            freqVec = [];
                        end
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                        
                        msgbox('Baseline Corrected Trials Saved');
                    else
                    end
                end
                
            catch
                errordlg('Error Baselining Data');
                return
            end
        end
        
        function downsampleData(app,PathName,FileName, new_value,cur_value)
            for F = 1 : numFiles %Go through each file
                if ischar(FileName)
                    curFile = [PathName FileName];
                else
                    curFile = [PathName FileName{F}];
                end
                
                %loading the file
                disp(['Resampling data in file: ' curFile]);
                load(curFile);
                
                %Resampling data
                theData = resample(theData,new_value,cur_value);
                
                %saving the file
                [fileOut,pathOut] = uiputfile('.mat');
                if fileOut ~=0
                    FS = new_value;
                    
                    epochS = exist ('epochStart', 'var');
                    if epochS == 1
                    else
                        epochStart = [];
                    end
                    epochE = exist ('epochEnd', 'var');
                    if epochE == 1
                    else
                        epochEnd = [];
                    end
                    foi = exist ('freqVec', 'var');
                    if foi == 1
                    else
                        freqVec = [];
                    end
                    itc = exist ('theData_ITC', 'var');
                    if itc == 1
                    else
                        theData_ITC = [];
                    end
                    
                    save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');
                    
                    msgbox('Resampled Data Saved');
                else
                end
            end
        end
        
        
    end
    
    methods (Access = private)
        
        function results = func4(app)
            
        end
    end
    

    methods (Access = private)

        % Menu selected function: LoadRawDataMenu
        function LoadRawDataMenuSelected(app, event)
            [FileName,PathName,FilterIndex] = uigetfile('*.*','Select EEG Data File', 'multiselect','off');
            if FilterIndex == 1
                
                filename = [PathName FileName];
                wb = waitbar(0,'Loading File ...');
                pause(0.1);
                [data,numChan,labels,txt,fs, triggersSeq] = readBDF_file(app,filename);
                hf = figure('units','normalized','outerposition',[0 0 1 1]);
                plot(data(1:160,:)');
                axis off
                axis tight
                waitbar(1,wb,'File Loaded');
                pause(.5);
                close(wb);
                
            else
            end
        end

        % Menu selected function: EpochDataMenu
        function EpochDataMenuSelected(app, event)
            answer = inputdlg({'Start Epoch (in seconds)'; 'End Epoch (in seconds)'; 'Sampling Rate (Hz)'; ...
                'Epoch on Triggers (1 = Yes)'; 'Save Epoched Data (1 = Yes)'},'Epoching Dataset');
            if isempty(answer)
                return
            else
                
                if isempty(answer{1}) | isempty(answer{2}) | isempty(answer{3}) | ...
                        isempty(str2num(answer{1})) | isempty(str2num(answer{2})) | isempty(str2num(answer{3})) | isempty(answer{4}) ...
                        | isempty(answer{5})
                    
                    errordlg('Enter Valid Epoch Values');
                    return
                else
                    if str2num(answer{1}) >= str2num(answer{2}) | str2num(answer{3}) <= 0
                        errordlg('Enter Valid Epoch Values');
                        return
                    else
                        %epoching parameters
                        app.epochStart = str2num(answer{1});
                        app.epochEnd = str2num(answer{2});
                        app.FS = str2num(answer{3});
                        app.lengthEpoch = round(((abs(app.epochStart)+abs(app.epochEnd))*app.FS));
                        app.epochTimeVec = app.epochStart:1/app.FS:(app.epochEnd - 1/app.FS);
                        app.epochOnTrigger = str2num(answer{4});
                        
                        saveData = str2num(answer{5});
                        %getting files to epoch
                        [FileName,PathName,FilterIndex] = uigetfile('*.*','Select EEG Neural Data Files to Epoch', 'multiselect','on');
                        if FilterIndex == 1
                            
                            if ischar(FileName) %only one file was selected
                                epochData(app,[PathName FileName],saveData);
                            else
                                %multiple files were selected
                                for ff = 1 : length(FileName)
                                    filename = [PathName FileName{ff}];
                                    %loading the file to epoch
                                    epochData(app,filename,saveData);
                                    
                                end
                            end
                            
                        else
                        end
                    end
                end
                
            end
        end

        % Menu selected function: BaselineDataMenu
        function BaselineDataMenuSelected(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({'Start Baseline Time (seconds)'; 'End Baseline Time (seconds)'},'Baselining');
                
                if isempty(answer)
                    return
                else
                    if isempty(answer{1}) | isempty(answer{2}) | ...
                            isempty(str2num(answer{1})) | isempty(str2num(answer{2})) | ...
                            str2num(answer{1}) >= str2num(answer{2})
                        
                        errordlg('Enter Valid Baseline Values');
                        return
                    else
                        startBase = str2num(answer{1});
                        endBase = str2num(answer{2});
                        
                        %calling baseline correction function
                        baselineData(app,PathName,FileName, startBase,endBase);
                    end
                end
                
            else
            end
        end

        % Menu selected function: ArtifactRejectDataMenu
        function ArtifactRejectDataMenuSelected(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({'Amplitude Value (microVolts)'},'Artifact Rejection');
                if isempty(answer)
                    return
                else
                    
                    %calling Art Rej function
                    artRej_criteria(app,PathName,FileName, answer);
                end
            else
            end
        end

        % Menu selected function: ResampleDataMenu
        function ResampleDataMenuSelected(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({'Current Sampling Rate (Hz)'; 'New Sampling Rate (Hz)'},'Resampling Data');
                 
                if isempty(answer)
                    return
                else
                    if isempty(str2num(answer{1})) | isempty(str2num(answer{2}))
                        
                        errordlg('Enter Valid Resampling Values');
                        return
                    else
                        cur_value = round(str2num(answer{1}));
                        new_value = round(str2num(answer{2}));
                        %calling downsampling data function
                        downsampleData(app,PathName,FileName,new_value,cur_value);
                    end
                end
                
            else
            end
        end

        % Menu selected function: ReferenceDataMenu
        function ReferenceDataMenuSelected(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({'Rereferencing Electrode(s) - [Separated by space]'},'Rereference Data');
                
                if isempty(answer)
                    return
                else
                    if isempty(str2num(answer{1}))
                        
                        errordlg('Enter Valid Electrode Number');
                        return
                    else
                        %extracting channels
                        refCh = []; aa = [];
                        aa = cell2mat(answer);
                        
                        if length(aa) == 1
                            refCh = str2num(aa);
                        else
                            b = findstr(aa, ' ');
                            s1 = 1;
                            for ii = 1 : length(b)
                                refCh = cat(1,refCh,str2num(aa(s1:b(ii) - 1)));
                                s1 = b(ii) + 1;
                            end
                        end
                        
                        if ~isempty(length(find(refCh < 1)))
                        else
                            %calling re-referencing data function
                            reReferenceData(app,PathName,FileName,refCh);
                        end
                        
                    end
                end
                
                
            else
            end
        end

        % Menu selected function: AverageDataMenu
        function AverageDataMenuSelected(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({['Time Domain Data = 1, Frequency Domain Data (e.g., FFT) = 2,' ...
                    'Time-Freq Domain Data (e.g., Wavelet) = 3']},'Averaging Data', [1 105]);
                
                if isempty(answer)
                    return
                else
                    if isempty(str2num(answer{1}))
                        errordlg('Enter Valid Data Type');
                        return
                    else
                        typeData = str2num(answer{1});
                        %calling downsampling data function
                        avgDataFile(app,PathName,FileName,typeData);
                    end
                end
                
            else
            end
        end

        % Menu selected function: FourierTransformMenu
        function FourierTransformMenuSelected(app, event)
            
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                %calling FFT data function
                fftDataAnalysis(app,PathName,FileName);
                
            else
            end
            
        end

        % Menu selected function: FilterDataMenu
        function FilterDataMenuSelected(app, event)
            TFAP_filtering_module
        end

        % Menu selected function: WaveletTransformMenu
        function WaveletTransformMenuSelected(app, event)
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                answer = inputdlg({'Min Freq (Hz)'; 'Max Freq (Hz)'; 'Wavelet Width (Hz)'},'Baselining');
                
                if isempty(answer)
                    return
                else
                    if isempty(answer{1}) | isempty(answer{2}) | isempty(answer{3}) | ...
                            isempty(str2num(answer{1})) | isempty(str2num(answer{2}))  | isempty(str2num(answer{3})) | ...
                            str2num(answer{1}) >= str2num(answer{2}) | ...
                            str2num(answer{1}) <= 0 | str2num(answer{2}) <= 0 str2num(answer{3}) <= 0
                        
                        errordlg('Enter Valid Baseline Values');
                        return
                    else
                        minFreq = str2num(answer{1});
                        maxFreq = str2num(answer{2});
                        foi = minFreq:maxFreq;
                        waveletwidth = round(str2num(answer{3}));
                        %calling the wavelet function
                        waveletTransform(app,PathName,FileName, foi,waveletwidth);
                    end
                end
                
            else
            end
        end

        % Menu selected function: RemoveChannelsfromFileMenu
        function RemoveChannelsfromFileMenuSelected(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreprocessDataMenu = uimenu(app.UIFigure);
            app.PreprocessDataMenu.Text = 'Pre-process Data';
            app.LoadRawDataMenu = uimenu(app.PreprocessDataMenu);
            app.LoadRawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRawDataMenuSelected, true);
            app.LoadRawDataMenu.Text = 'Load Raw Data';
            app.RemoveChannelsfromFileMenu = uimenu(app.PreprocessDataMenu);
            app.RemoveChannelsfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveChannelsfromFileMenuSelected, true);
            app.RemoveChannelsfromFileMenu.Text = 'Remove Channels from File';
            app.EpochDataMenu = uimenu(app.PreprocessDataMenu);
            app.EpochDataMenu.MenuSelectedFcn = createCallbackFcn(app, @EpochDataMenuSelected, true);
            app.EpochDataMenu.Text = 'Epoch Data';
            app.BaselineDataMenu = uimenu(app.PreprocessDataMenu);
            app.BaselineDataMenu.MenuSelectedFcn = createCallbackFcn(app, @BaselineDataMenuSelected, true);
            app.BaselineDataMenu.Text = 'Baseline Data';
            app.ArtifactRejectDataMenu = uimenu(app.PreprocessDataMenu);
            app.ArtifactRejectDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ArtifactRejectDataMenuSelected, true);
            app.ArtifactRejectDataMenu.Text = 'Artifact Reject Data';
            app.EyeMovementRejectionMenu = uimenu(app.PreprocessDataMenu);
            app.EyeMovementRejectionMenu.Text = 'Eye Movement Rejection';
            app.ResampleDataMenu = uimenu(app.PreprocessDataMenu);
            app.ResampleDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ResampleDataMenuSelected, true);
            app.ResampleDataMenu.Text = 'Resample Data';
            app.ReferenceDataMenu = uimenu(app.PreprocessDataMenu);
            app.ReferenceDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReferenceDataMenuSelected, true);
            app.ReferenceDataMenu.Text = 'Reference Data';
            app.AverageDataMenu = uimenu(app.PreprocessDataMenu);
            app.AverageDataMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageDataMenuSelected, true);
            app.AverageDataMenu.Text = 'Average Data';
            app.FrequencyAnalysisMenu = uimenu(app.UIFigure);
            app.FrequencyAnalysisMenu.Text = 'Frequency Analysis';
            app.FilterDataMenu = uimenu(app.FrequencyAnalysisMenu);
            app.FilterDataMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterDataMenuSelected, true);
            app.FilterDataMenu.Text = 'Filter Data';
            app.FourierTransformMenu = uimenu(app.FrequencyAnalysisMenu);
            app.FourierTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @FourierTransformMenuSelected, true);
            app.FourierTransformMenu.Text = 'Fourier Transform';
            app.WaveletTransformMenu = uimenu(app.FrequencyAnalysisMenu);
            app.WaveletTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @WaveletTransformMenuSelected, true);
            app.WaveletTransformMenu.Text = 'Wavelet Transform';
            app.CrossElectrodeCoherenceMenu = uimenu(app.FrequencyAnalysisMenu);
            app.CrossElectrodeCoherenceMenu.Text = 'Cross Electrode Coherence';
            app.OperationsMenu = uimenu(app.UIFigure);
            app.OperationsMenu.Text = 'Operations';
            app.AddDataMenu = uimenu(app.OperationsMenu);
            app.AddDataMenu.Text = 'Add Data';
            app.SubtractDataMenu = uimenu(app.OperationsMenu);
            app.SubtractDataMenu.Text = 'Subtract Data';
            app.ExportMenu = uimenu(app.OperationsMenu);
            app.ExportMenu.Text = 'Export';
            app.PlottingMenu = uimenu(app.UIFigure);
            app.PlottingMenu.Text = 'Plotting';
            app.PlotTimeDataMenu = uimenu(app.PlottingMenu);
            app.PlotTimeDataMenu.Text = 'Plot Time Data';
            app.PlotFFTDataMenu = uimenu(app.PlottingMenu);
            app.PlotFFTDataMenu.Text = 'Plot FFT Data';
            app.PlottimeFrequencyDataMenu = uimenu(app.PlottingMenu);
            app.PlottimeFrequencyDataMenu.Text = 'Plot time-Frequency Data';
            app.BatchAnalysisMenu = uimenu(app.UIFigure);
            app.BatchAnalysisMenu.Text = 'Batch Analysis';
            app.text1 = uilabel(app.UIFigure);
            app.text1.Text = 'Analysis Package for:';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Text = 'Time Domain Data';
            app.text4 = uilabel(app.UIFigure);
            app.text4.Text = 'Frequency Domain Data';
            app.text5 = uilabel(app.UIFigure);
            app.text5.Text = 'Time-Frequency Domain Data';
            app.text6 = uilabel(app.UIFigure);
            app.text6.Text = '&';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TFAP_2pt0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end