classdef TFAP_filtering_module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LowPassFilterIIRPanel           matlab.ui.container.Panel
        ViewFilterButton_lowPass        matlab.ui.control.Button
        FilterorderEditFieldLabel       matlab.ui.control.Label
        FilterorderEditField_lowPass    matlab.ui.control.NumericEditField
        DesignLowPassFilterCheckBox     matlab.ui.control.CheckBox
        MaxFrequencyHzEditFieldLabel    matlab.ui.control.Label
        MaxFrequencyHzEditField_lowPass  matlab.ui.control.NumericEditField
        PassbandRippledbEditFieldLabel  matlab.ui.control.Label
        PassbandRippledbEditField_lowPass  matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        HilbertTransformCheckBox        matlab.ui.control.CheckBox
        FilterDataButton                matlab.ui.control.Button
        HighPassFilterIIRPanel          matlab.ui.container.Panel
        ViewFilterButton_highPass       matlab.ui.control.Button
        FilterorderEditFieldLabel_2     matlab.ui.control.Label
        FilterorderEditField_highPass   matlab.ui.control.NumericEditField
        DesignHighPassFilterCheckBox    matlab.ui.control.CheckBox
        MinFrequencyHzLabel             matlab.ui.control.Label
        MaxFrequencyHzEditField_highPass  matlab.ui.control.NumericEditField
        PassbandRippledbEditFieldLabel_2  matlab.ui.control.Label
        PassbandRippledbEditField_highPass  matlab.ui.control.NumericEditField
        BandPassFilterIIRPanel          matlab.ui.container.Panel
        ViewFilterButton_bandPass       matlab.ui.control.Button
        FilterorderEditFieldLabel_3     matlab.ui.control.Label
        FilterorderEditField_bandPass   matlab.ui.control.NumericEditField
        DesignBandPassFilterCheckBox    matlab.ui.control.CheckBox
        MinFrequencyHzLabel_2           matlab.ui.control.Label
        MinFrequencyHzEditField_bandPass  matlab.ui.control.NumericEditField
        MaxFrequencyHzLabel             matlab.ui.control.Label
        MaxFrequencyHzEditField_bandPass  matlab.ui.control.NumericEditField
        BandStopFilterIIRPanel          matlab.ui.container.Panel
        ViewFilterButton_bandStop       matlab.ui.control.Button
        FilterorderEditFieldLabel_4     matlab.ui.control.Label
        FilterorderEditField_bandStop   matlab.ui.control.NumericEditField
        DesignBandStopFilterCheckBox    matlab.ui.control.CheckBox
        MinFrequencyHzLabel_3           matlab.ui.control.Label
        MinFrequencyHzEditField_bandStop  matlab.ui.control.NumericEditField
        MaxFrequencyHzLabel_2           matlab.ui.control.Label
        MaxFrequencyHzEditField_bandStop  matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function results = filterDataAnalysis(app,PathName,FileName,filterData)
            try
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                for F = 1 : numFiles %Go through each file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
                    
                    disp(['Filtering Data in File: ' curFile]);
                    
                    %loading the file
                    load(curFile);
                    dimData = ndims(theData);
                    numEpoch = [];
                    %determining whether data is single trial or averaged
                    if dimData < 3
                        filterData = zeros(size(theData,1),size(theData,2));
                        numEpoch = 1;
                    else
                        filterData = zeros(size(theData,1),size(theData,2),size(theData,3));
                        numEpoch = size(theData,3);
                    end
                    
                    for n = 1 : numEpoch
                        curData = [];                        
                        
                        if numEpoch == 1
                            curData = theData;
                        else
                            curData = theData(:,:,n);
                        end
                        
                        %-----------
                        %applying filters
                        
                        %bandstop
                        if ~isnan(filterData.bandstop)
                            curData = filtfilt(filterData.bandstop.param,curData);
                        else
                        end
                        
                        %lowpass
                        if ~isnan(filterData.lowPass)
                            curData = filtfilt(filterData.lowPass.param,curData);
                        else
                        end
                        
                        %highpass
                        if ~isnan(filterData.highPass)
                            curData = filtfilt(filterData.highPass.param,curData);
                        else
                        end
                        
                        %bandpass
                        if ~isnan(filterData.bandpass)
                            curData = filtfilt(filterData.highPass.param,curData);
                        else
                        end
                        %-----------
                        
                        
                        % applying hilbert transform
                        if filterData.hilbert == 1
                            curData = abs(hilbert(curData));
                        else
                        end
                        
                        %-----------                        
                        if numEpoch == 1
                            filterData = curData;
                        else                            
                            filterData(:,:,n) = curData;
                        end                        
                        
                    end
                    
                    
                    theData = filterData;
                    %saving the file
                    [fileOut,pathOut] = uiputfile('.mat');
                    if fileOut ~=0
                        FS = app.SamplingRateHzEditField.value;
                        epochS = exist ('epochStart', 'var');
                        if epochS == 1
                        else
                            epochStart = [];
                        end
                        epochE = exist ('epochEnd', 'var');
                        if epochE == 1
                        else
                            epochEnd = [];
                        end
                        foi = exist ('freqVec', 'var');
                        if foi == 1
                        else
                            freqVec = [];
                        end
                        itc = exist ('theData_ITC', 'var');
                        if itc == 1
                        else
                            theData_ITC = [];
                        end
                        
                        save([pathOut fileOut],'theData', 'theData_ITC', 'freqVec', 'FS', 'epochStart', 'epochEnd',  '-v7.3');                        
                        msgbox('Filtered data saved');
                    else
                    end
                end
                
            catch
                errordlg('Error Filtering data');
                return
            end
        end
    end
    

    methods (Access = private)

        % Value changed function: MaxFrequencyHzEditField_lowPass
        function MaxFrequencyHzEditField_lowPassValueChanged(app, event)
            value = app.MaxFrequencyHzEditField_lowPass.Value;
            if value < 0
                app.MaxFrequencyHzEditField_lowPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: PassbandRippledbEditField_lowPass
        function PassbandRippledbEditField_lowPassValueChanged(app, event)
            value = app.PassbandRippledbEditField_lowPass.Value;
            if value < 0
                app.PassbandRippledbEditField_lowPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: FilterorderEditField_lowPass
        function FilterorderEditField_lowPassValueChanged(app, event)
            value = app.FilterorderEditField_lowPass.Value;
            value = round(value);
            if value < 1
                app.FilterorderEditField_lowPass.Value = 1;
                pause(.05);
            end
        end

        % Value changed function: DesignLowPassFilterCheckBox
        function DesignLowPassFilterCheckBoxValueChanged(app, event)
            value = app.DesignLowPassFilterCheckBox.Value;
            if value == 1
                app.FilterorderEditField_lowPass.Editable = 1;
                app.PassbandRippledbEditField_lowPass.Editable = 1;
                app.MaxFrequencyHzEditField_lowPass.Editable = 1;
                app.ViewFilterButton_lowPass.Enable = 1;
                pause(.05);
            else
                app.FilterorderEditField_lowPass.Editable = 0;
                app.PassbandRippledbEditField_lowPass.Editable = 0;
                app.MaxFrequencyHzEditField_lowPass.Editable = 0;
                app.ViewFilterButton_lowPass.Enable = 0;
                pause(.05);
            end
        end

        % Button pushed function: ViewFilterButton_lowPass
        function ViewFilterButton_lowPassPushed(app, event)
            
            filt_order = app.FilterorderEditField_lowPass.Value;
            maxFreq = app.MaxFrequencyHzEditField_lowPass.Value;
            passRipple = app.PassbandRippledbEditField_lowPass.Value;
            fs_rate = app.SamplingRateHzEditField.Value;
            
            lpFilt = designfilt('lowpassiir','FilterOrder',filt_order, ...
                'PassbandFrequency',maxFreq,'PassbandRipple',passRipple, ...
                'SampleRate',fs_rate);
            fvtool(lpFilt);
            
        end

        % Button pushed function: ViewFilterButton_highPass
        function ViewFilterButton_highPassPushed(app, event)
            filt_order = app.FilterorderEditField_highPass.Value;
            maxFreq = app.MaxFrequencyHzEditField_highPass.Value;
            passRipple = app.PassbandRippledbEditField_highPass.Value;
            fs_rate = app.SamplingRateHzEditField.Value;
            
            lpFilt = designfilt('highpassiir','FilterOrder',filt_order, ...
                'PassbandFrequency',maxFreq,'PassbandRipple',passRipple, ...
                'SampleRate',fs_rate);
            fvtool(lpFilt);
        end

        % Value changed function: FilterorderEditField_highPass
        function FilterorderEditField_highPassValueChanged(app, event)
            value = app.FilterorderEditField_highPass.Value;
            value = round(value);
            if value < 1
                app.FilterorderEditField_highPass.Value = 1;
                pause(.05);
            end
        end

        % Value changed function: MaxFrequencyHzEditField_highPass
        function MaxFrequencyHzEditField_highPassValueChanged(app, event)
            value = app.MaxFrequencyHzEditField_highPass.Value;
            if value < 0
                app.MaxFrequencyHzEditField_highPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: PassbandRippledbEditField_highPass
        function PassbandRippledbEditField_highPassValueChanged(app, event)
            value = app.PassbandRippledbEditField_highPass.Value;
            if value < 0
                app.PassbandRippledbEditField_highPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: DesignHighPassFilterCheckBox
        function DesignHighPassFilterCheckBoxValueChanged(app, event)
            value = app.DesignHighPassFilterCheckBox.Value;
            if value == 1
                app.FilterorderEditField_highPass.Editable = 1;
                app.PassbandRippledbEditField_highPass.Editable = 1;
                app.MaxFrequencyHzEditField_highPass.Editable = 1;
                app.ViewFilterButton_highPass.Enable = 1;
                pause(.05);
            else
                app.FilterorderEditField_highPass.Editable = 0;
                app.PassbandRippledbEditField_highPass.Editable = 0;
                app.MaxFrequencyHzEditField_highPass.Editable = 0;
                app.ViewFilterButton_highPass.Enable = 0;
                pause(.05);
            end
        end

        % Value changed function: DesignBandPassFilterCheckBox
        function DesignBandPassFilterCheckBoxValueChanged(app, event)
            value = app.DesignBandPassFilterCheckBox.Value;
            if value == 1
                app.FilterorderEditField_bandPass.Editable = 1;
                app.MinFrequencyHzEditField_bandPass.Editable = 1;
                app.MaxFrequencyHzEditField_bandPass.Editable = 1;
                app.ViewFilterButton_bandPass.Enable = 1;
                pause(.05);
            else
                app.FilterorderEditField_bandPass.Editable = 0;
                app.MinFrequencyHzEditField_bandPass.Editable = 0;
                app.MaxFrequencyHzEditField_bandPass.Editable = 0;
                app.ViewFilterButton_bandPass.Enable = 0;
                pause(.05);
            end
        end

        % Value changed function: FilterorderEditField_bandPass
        function FilterorderEditField_bandPassValueChanged(app, event)
            value = app.FilterorderEditField_bandPass.Value;
            value = round(value);
            %making it an even filter order coeff
            if mod(value,2) == 1
                value = value + 1;
                app.FilterorderEditField_bandPass.Value = value;
                pause(0.05);
            else
            end
            if value < 1
                app.FilterorderEditField_bandPass.Value = 1;
                pause(.05);
            end
        end

        % Value changed function: MinFrequencyHzEditField_bandPass
        function MinFrequencyHzEditField_bandPassValueChanged(app, event)
            value = app.MinFrequencyHzEditField_bandPass.Value;
            if value < 0
                app.MinFrequencyHzEditField_bandPass.Value = 0;
                pause(.05);
            end
        end

        % Callback function
        function PassbandRippledbEditField_bandPassValueChanged(app, event)
            value = app.PassbandRippledbEditField_bandPass.Value;
            if value < 0
                app.PassbandRippledbEditField_bandPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: MaxFrequencyHzEditField_bandPass
        function MaxFrequencyHzEditField_bandPassValueChanged(app, event)
            value = app.MaxFrequencyHzEditField_bandPass.Value;
            if value < 0
                app.MaxFrequencyHzEditField_bandPass.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: DesignBandStopFilterCheckBox
        function DesignBandStopFilterCheckBoxValueChanged(app, event)
            value = app.DesignBandStopFilterCheckBox.Value;
            if value == 1
                app.FilterorderEditField_bandStop.Editable = 1;
                app.MinFrequencyHzEditField_bandStop.Editable = 1;
                app.MaxFrequencyHzEditField_bandStop.Editable = 1;
                app.ViewFilterButton_bandStop.Enable = 1;
                pause(.05);
            else
                app.FilterorderEditField_bandStop.Editable = 0;
                app.MinFrequencyHzEditField_bandStop.Editable = 0;
                app.MaxFrequencyHzEditField_bandStop.Editable = 0;
                app.ViewFilterButton_bandStop.Enable = 0;
                pause(.05);
            end
        end

        % Value changed function: FilterorderEditField_bandStop
        function FilterorderEditField_bandStopValueChanged(app, event)
            value = app.FilterorderEditField_bandStop.Value;
            value = round(value);
            if mod(value,2) == 1
                value = value + 1;
                app.FilterorderEditField_bandStop.Value = value;
                pause(0.05);
            else
            end
            
            if value < 1
                app.FilterorderEditField_bandStop.Value = 1;
                pause(.05);
            end
        end

        % Value changed function: MinFrequencyHzEditField_bandStop
        function MinFrequencyHzEditField_bandStopValueChanged(app, event)
            value = app.MinFrequencyHzEditField_bandStop.Value;
            if value < 0
                app.MinFrequencyHzEditField_bandStop.Value = 0;
                pause(.05);
            end
        end

        % Value changed function: MaxFrequencyHzEditField_bandStop
        function MaxFrequencyHzEditField_bandStopValueChanged(app, event)
            value = app.MaxFrequencyHzEditField_bandStop.Value;
            if value < 0
                app.MaxFrequencyHzEditField_bandStop.Value = 0;
                pause(.05);
            end
        end

        % Callback function
        function PassbandRippledbEditField_bandStopValueChanged(app, event)
            value = app.PassbandRippledbEditField_bandStop.Value;
            if value < 0
                app.PassbandRippledbEditField_bandStop.Value = 0;
                pause(.05);
            end
        end

        % Button pushed function: FilterDataButton
        function FilterDataButtonPushed(app, event)
            %Selecting files
            [FileName,PathName,FilterIndex] = uigetfile({'.mat'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 1
                
                if ischar(FileName)% only one file was selected
                    numFiles = 1;
                else %multiple files were selected
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
                
                filterData = [];
                filterData.lowPass = nan;
                filterData.highPass = nan;
                filterData.bandstop = nan;
                filterData.bandpass = nan;
                filterData.fs = app.SamplingRateHzEditField.value;
                filterData.hilbert = app.HilbertTransformCheckBox.Value;
                
                if app.DesignLowPassFilterCheckBox.Value == 1
                    filterData.lowPass = 1;
                    
                    filt_order = app.FilterorderEditField_lowPass.Value;
                    maxFreq = app.MaxFrequencyHzEditField_lowPass.Value;
                    passRipple = app.PassbandRippledbEditField_lowPass.Value;
                    fs_rate = app.SamplingRateHzEditField.Value;
                    
                    filterData.lowPass.param = designfilt('lowpassiir','FilterOrder',filt_order, ...
                        'PassbandFrequency',maxFreq,'PassbandRipple',passRipple, ...
                        'SampleRate',fs_rate);
                else
                end
                
                if app.DesignHighPassFilterCheckBox.Value == 1
                    filterData.highPass = 1;
                    
                    filt_order = app.FilterorderEditField_highPass.Value;
                    maxFreq = app.MaxFrequencyHzEditField_highPass.Value;
                    passRipple = app.PassbandRippledbEditField_highPass.Value;
                    fs_rate = app.SamplingRateHzEditField.Value;
                    
                    filterData.highPass.param = designfilt('highpassiir','FilterOrder',filt_order, ...
                        'PassbandFrequency',maxFreq,'PassbandRipple',passRipple, ...
                        'SampleRate',fs_rate);
                else
                end
                
                if app.DesignBandStopFilterCheckBox.Value == 1
                    filterData.bandstop = 1;
                    
                    filt_order = app.FilterorderEditField_bandStop.Value;
                    minFreq = app.MinFrequencyHzEditField_bandStop.Value;
                    maxFreq = app.MaxFrequencyHzEditField_bandStop.Value;
                    fs_rate = app.SamplingRateHzEditField.Value;
                    
                    filterData.bandstop.param = designfilt('bandstopiir','FilterOrder',filt_order, ...
                        'HalfPowerFrequency1',minFreq,'HalfPowerFrequency2',maxFreq, ...
                        'SampleRate',fs_rate);
                else
                end
                
                if app.DesignBandPassFilterCheckBox.Value == 1
                    filterData.bandpass = 1;
                    
                    filt_order = app.FilterorderEditField_bandPass.Value;
                    minFreq = app.MinFrequencyHzEditField_bandPass.Value;
                    maxFreq = app.MaxFrequencyHzEditField_bandPass.Value;
                    fs_rate = app.SamplingRateHzEditField.Value;
                    
                    filterData.bandpass.param = designfilt('bandpassiir','FilterOrder',filt_order, ...
                        'HalfPowerFrequency1',minFreq,'HalfPowerFrequency2',maxFreq, ...
                        'SampleRate',fs_rate);
                    
                else
                end
                %calling FFT data function
                results = filterDataAnalysis(app,PathName,FileName,filterData);
                
            else
            end
        end

        % Button pushed function: ViewFilterButton_bandStop
        function ViewFilterButton_bandStopPushed(app, event)
            filt_order = app.FilterorderEditField_bandStop.Value;
            minFreq = app.MinFrequencyHzEditField_bandStop.Value;
            maxFreq = app.MaxFrequencyHzEditField_bandStop.Value;
            fs_rate = app.SamplingRateHzEditField.Value;
            
            lpFilt = designfilt('bandstopiir','FilterOrder',filt_order, ...
                'HalfPowerFrequency1',minFreq,'HalfPowerFrequency2',maxFreq, ...
                'SampleRate',fs_rate);
            fvtool(lpFilt);
        end

        % Button pushed function: ViewFilterButton_bandPass
        function ViewFilterButton_bandPassPushed(app, event)
            filt_order = app.FilterorderEditField_bandPass.Value;
            minFreq = app.MinFrequencyHzEditField_bandPass.Value;
            maxFreq = app.MaxFrequencyHzEditField_bandPass.Value;
            fs_rate = app.SamplingRateHzEditField.Value;
            
            lpFilt = designfilt('bandpassiir','FilterOrder',filt_order, ...
                'HalfPowerFrequency1',minFreq,'HalfPowerFrequency2',maxFreq, ...
                'SampleRate',fs_rate);
            fvtool(lpFilt);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LowPassFilterIIRPanel = uipanel(app.UIFigure);
            app.LowPassFilterIIRPanel.Title = 'Low-Pass Filter (IIR)';
            app.ViewFilterButton_lowPass = uibutton(app.LowPassFilterIIRPanel, 'push');
            app.ViewFilterButton_lowPass.ButtonPushedFcn = createCallbackFcn(app, @ViewFilterButton_lowPassPushed, true);
            app.ViewFilterButton_lowPass.Enable = 'off';
            app.ViewFilterButton_lowPass.Text = 'View Filter';
            app.FilterorderEditFieldLabel = uilabel(app.LowPassFilterIIRPanel);
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.FilterorderEditField_lowPass = uieditfield(app.LowPassFilterIIRPanel, 'numeric');
            app.FilterorderEditField_lowPass.ValueChangedFcn = createCallbackFcn(app, @FilterorderEditField_lowPassValueChanged, true);
            app.FilterorderEditField_lowPass.Editable = 'off';
            app.FilterorderEditField_lowPass.Value = 4;
            app.DesignLowPassFilterCheckBox = uicheckbox(app.LowPassFilterIIRPanel);
            app.DesignLowPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DesignLowPassFilterCheckBoxValueChanged, true);
            app.DesignLowPassFilterCheckBox.Text = 'Design Low Pass Filter';
            app.MaxFrequencyHzEditFieldLabel = uilabel(app.LowPassFilterIIRPanel);
            app.MaxFrequencyHzEditFieldLabel.Text = 'Max Frequency (Hz)';
            app.MaxFrequencyHzEditField_lowPass = uieditfield(app.LowPassFilterIIRPanel, 'numeric');
            app.MaxFrequencyHzEditField_lowPass.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyHzEditField_lowPassValueChanged, true);
            app.MaxFrequencyHzEditField_lowPass.Editable = 'off';
            app.MaxFrequencyHzEditField_lowPass.Value = 0.1;
            app.PassbandRippledbEditFieldLabel = uilabel(app.LowPassFilterIIRPanel);
            app.PassbandRippledbEditFieldLabel.Text = 'Pass-band Ripple (db)';
            app.PassbandRippledbEditField_lowPass = uieditfield(app.LowPassFilterIIRPanel, 'numeric');
            app.PassbandRippledbEditField_lowPass.ValueChangedFcn = createCallbackFcn(app, @PassbandRippledbEditField_lowPassValueChanged, true);
            app.PassbandRippledbEditField_lowPass.Editable = 'off';
            app.PassbandRippledbEditField_lowPass.Value = 0.5;
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHzEditField.Value = 1000;
            app.HilbertTransformCheckBox = uicheckbox(app.UIFigure);
            app.HilbertTransformCheckBox.Text = '    Hilbert Transform';
            app.FilterDataButton = uibutton(app.UIFigure, 'push');
            app.FilterDataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterDataButtonPushed, true);
            app.FilterDataButton.Text = 'Filter Data';
            app.HighPassFilterIIRPanel = uipanel(app.UIFigure);
            app.HighPassFilterIIRPanel.Title = 'High-Pass Filter (IIR)';
            app.ViewFilterButton_highPass = uibutton(app.HighPassFilterIIRPanel, 'push');
            app.ViewFilterButton_highPass.ButtonPushedFcn = createCallbackFcn(app, @ViewFilterButton_highPassPushed, true);
            app.ViewFilterButton_highPass.Enable = 'off';
            app.ViewFilterButton_highPass.Text = 'View Filter';
            app.FilterorderEditFieldLabel_2 = uilabel(app.HighPassFilterIIRPanel);
            app.FilterorderEditFieldLabel_2.Text = 'Filter order';
            app.FilterorderEditField_highPass = uieditfield(app.HighPassFilterIIRPanel, 'numeric');
            app.FilterorderEditField_highPass.ValueChangedFcn = createCallbackFcn(app, @FilterorderEditField_highPassValueChanged, true);
            app.FilterorderEditField_highPass.Editable = 'off';
            app.FilterorderEditField_highPass.Value = 4;
            app.DesignHighPassFilterCheckBox = uicheckbox(app.HighPassFilterIIRPanel);
            app.DesignHighPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DesignHighPassFilterCheckBoxValueChanged, true);
            app.DesignHighPassFilterCheckBox.Text = 'Design High Pass Filter';
            app.MinFrequencyHzLabel = uilabel(app.HighPassFilterIIRPanel);
            app.MinFrequencyHzLabel.Text = 'Min Frequency (Hz)';
            app.MaxFrequencyHzEditField_highPass = uieditfield(app.HighPassFilterIIRPanel, 'numeric');
            app.MaxFrequencyHzEditField_highPass.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyHzEditField_highPassValueChanged, true);
            app.MaxFrequencyHzEditField_highPass.Editable = 'off';
            app.MaxFrequencyHzEditField_highPass.Value = 30;
            app.PassbandRippledbEditFieldLabel_2 = uilabel(app.HighPassFilterIIRPanel);
            app.PassbandRippledbEditFieldLabel_2.Text = 'Pass-band Ripple (db)';
            app.PassbandRippledbEditField_highPass = uieditfield(app.HighPassFilterIIRPanel, 'numeric');
            app.PassbandRippledbEditField_highPass.ValueChangedFcn = createCallbackFcn(app, @PassbandRippledbEditField_highPassValueChanged, true);
            app.PassbandRippledbEditField_highPass.Editable = 'off';
            app.PassbandRippledbEditField_highPass.Value = 0.5;
            app.BandPassFilterIIRPanel = uipanel(app.UIFigure);
            app.BandPassFilterIIRPanel.Title = 'Band-Pass Filter (IIR)';
            app.ViewFilterButton_bandPass = uibutton(app.BandPassFilterIIRPanel, 'push');
            app.ViewFilterButton_bandPass.ButtonPushedFcn = createCallbackFcn(app, @ViewFilterButton_bandPassPushed, true);
            app.ViewFilterButton_bandPass.Enable = 'off';
            app.ViewFilterButton_bandPass.Text = 'View Filter';
            app.FilterorderEditFieldLabel_3 = uilabel(app.BandPassFilterIIRPanel);
            app.FilterorderEditFieldLabel_3.Text = 'Filter order';
            app.FilterorderEditField_bandPass = uieditfield(app.BandPassFilterIIRPanel, 'numeric');
            app.FilterorderEditField_bandPass.ValueChangedFcn = createCallbackFcn(app, @FilterorderEditField_bandPassValueChanged, true);
            app.FilterorderEditField_bandPass.Editable = 'off';
            app.FilterorderEditField_bandPass.Value = 4;
            app.DesignBandPassFilterCheckBox = uicheckbox(app.BandPassFilterIIRPanel);
            app.DesignBandPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DesignBandPassFilterCheckBoxValueChanged, true);
            app.DesignBandPassFilterCheckBox.Text = 'Design Band Pass Filter';
            app.MinFrequencyHzLabel_2 = uilabel(app.BandPassFilterIIRPanel);
            app.MinFrequencyHzLabel_2.Text = 'Min Frequency (Hz)';
            app.MinFrequencyHzEditField_bandPass = uieditfield(app.BandPassFilterIIRPanel, 'numeric');
            app.MinFrequencyHzEditField_bandPass.ValueChangedFcn = createCallbackFcn(app, @MinFrequencyHzEditField_bandPassValueChanged, true);
            app.MinFrequencyHzEditField_bandPass.Editable = 'off';
            app.MinFrequencyHzEditField_bandPass.Value = 0.1;
            app.MaxFrequencyHzLabel = uilabel(app.BandPassFilterIIRPanel);
            app.MaxFrequencyHzLabel.Text = 'Max Frequency (Hz)';
            app.MaxFrequencyHzEditField_bandPass = uieditfield(app.BandPassFilterIIRPanel, 'numeric');
            app.MaxFrequencyHzEditField_bandPass.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyHzEditField_bandPassValueChanged, true);
            app.MaxFrequencyHzEditField_bandPass.Editable = 'off';
            app.MaxFrequencyHzEditField_bandPass.Value = 30;
            app.BandStopFilterIIRPanel = uipanel(app.UIFigure);
            app.BandStopFilterIIRPanel.Title = 'Band-Stop Filter (IIR)';
            app.ViewFilterButton_bandStop = uibutton(app.BandStopFilterIIRPanel, 'push');
            app.ViewFilterButton_bandStop.ButtonPushedFcn = createCallbackFcn(app, @ViewFilterButton_bandStopPushed, true);
            app.ViewFilterButton_bandStop.Enable = 'off';
            app.ViewFilterButton_bandStop.Text = 'View Filter';
            app.FilterorderEditFieldLabel_4 = uilabel(app.BandStopFilterIIRPanel);
            app.FilterorderEditFieldLabel_4.Text = 'Filter order';
            app.FilterorderEditField_bandStop = uieditfield(app.BandStopFilterIIRPanel, 'numeric');
            app.FilterorderEditField_bandStop.ValueChangedFcn = createCallbackFcn(app, @FilterorderEditField_bandStopValueChanged, true);
            app.FilterorderEditField_bandStop.Editable = 'off';
            app.FilterorderEditField_bandStop.Value = 4;
            app.DesignBandStopFilterCheckBox = uicheckbox(app.BandStopFilterIIRPanel);
            app.DesignBandStopFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DesignBandStopFilterCheckBoxValueChanged, true);
            app.DesignBandStopFilterCheckBox.Text = 'Design Band Stop Filter';
            app.MinFrequencyHzLabel_3 = uilabel(app.BandStopFilterIIRPanel);
            app.MinFrequencyHzLabel_3.Text = 'Min Frequency (Hz)';
            app.MinFrequencyHzEditField_bandStop = uieditfield(app.BandStopFilterIIRPanel, 'numeric');
            app.MinFrequencyHzEditField_bandStop.ValueChangedFcn = createCallbackFcn(app, @MinFrequencyHzEditField_bandStopValueChanged, true);
            app.MinFrequencyHzEditField_bandStop.Editable = 'off';
            app.MinFrequencyHzEditField_bandStop.Value = 59.5;
            app.MaxFrequencyHzLabel_2 = uilabel(app.BandStopFilterIIRPanel);
            app.MaxFrequencyHzLabel_2.Text = 'Max Frequency (Hz)';
            app.MaxFrequencyHzEditField_bandStop = uieditfield(app.BandStopFilterIIRPanel, 'numeric');
            app.MaxFrequencyHzEditField_bandStop.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyHzEditField_bandStopValueChanged, true);
            app.MaxFrequencyHzEditField_bandStop.Editable = 'off';
            app.MaxFrequencyHzEditField_bandStop.Value = 60.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = TFAP_filtering_module

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end