classdef cross_electrodes_coherence_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        channelList   matlab.ui.control.ListBox
        channelList2  matlab.ui.control.ListBox
        text1         matlab.ui.control.Label
        text2         matlab.ui.control.Label
        analyzeCoh    matlab.ui.control.Button
        exitButton    matlab.ui.control.Button
        SelectElectrodesforCoherenceAnalysisLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function cross_electrodes_coherence_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to cross_electrodes_coherence (see VARARGIN)
%             
%             % Choose default command line output for cross_electrodes_coherence
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes cross_electrodes_coherence wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             % myD = matlabroot;
%             % myD = [myD '\TFAP\'];
%             % load([myD 'numElecs_file']);
%             % channelList = {};
%             % for i = 1 : numCH
%             %     channelList{i} = num2str(i);
%             % end
%             % channelList = channelList';
%             % set(app.channelList2,'String', channelList, 'value', 1);
%             % set(app.channelList2,'HorizontalAlignment', 'center');
%             % set(app.channelList,'String', channelList, 'value', 1);
%             % set(app.channelList,'HorizontalAlignment', 'center');
%             
%             dirMe = matlabroot;
%             dirMe = [dirMe '\TFAP\'];
%             load([dirMe 'ElecCoord_CrossCoherence.mat'])
%             channelList = elecLabels;
%             set(app.channelList,'String', channelList, 'value', 1);
%             set(app.channelList,'HorizontalAlignment', 'center');
%             
%             set(app.channelList2,'String', channelList, 'value', 1);
%             set(app.channelList2,'HorizontalAlignment', 'center');
        end

        % Button pushed function: analyzeCoh
        function analyzeCoh_Callback(app, event)
            % hObject    handle to analyzeCoh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            options.Resize ='on';
            options.WindowStyle ='normal';
            answer = inputdlg({'Wavelet Analysis Start Frequency (Hz)';'Wavelet Analysis End Frequency (Hz)' ; ...
                'Frequency Steps'; 'Window Scale (Typically 6)'},'Morlet Wavelet Analysis', 1,{'1'; '1'; '1'; '6'}, options);
            
            if isempty(answer{1}) | isempty(str2num(answer{2})) | isempty(str2num(answer{3})) | isempty(str2num(answer{4})) |...
                    (str2num(answer{1}) <= 0) | (str2num(answer{2}) <= 0) | (str2num(answer{3}) <= 0) | (str2num(answer{4}) <= 0) | ...
                    (str2num(answer{1}) > str2num(answer{2}))
                errordlg('Enter Valid Wavelet Values');
                return
            else
            end
            
            CHOI = get(app.channelList,'value');
            CHOI2 = get(app.channelList2,'value');
            
            labelChannels = get(app.channelList,'String');
            labelChannels2 = get(app.channelList,'String');
            
            lowFreq = str2num(answer{1});
            highFreq = str2num(answer{2});
            filterHz(1) = str2num(answer{3});
            filterHz(2) = str2num(answer{4});
            
            PLV = [];
            PLV_count = 0;
            [FileName,PathName,FilterIndex] = uigetfile({'*.stt'},'SELECT FILES', 'multiselect','on');
            if FilterIndex == 0
            else
            
                if ischar(FileName)
                    numFiles = 1;
                else
                    numFiles = length(FileName);
                    FileName = sort(FileName);
                end
            
            
                for F = 1 : numFiles %Go through each .cnt file
                    if ischar(FileName)
                        curFile = [PathName FileName];
                    else
                        curFile = [PathName FileName{F}];
                    end
            
                    if ~isempty(strfind(lower(curFile),'.stt'))
            
                        load(curFile,'-mat');
            
                        temp = squeeze(mean(epochERP(:,CHOI,:),2));
                        temp2 = squeeze(mean(epochERP(:,CHOI2,:),2));
            
            
                        for n = 1 : size(temp,2)
            
                            po = [];
                            ph = [];
                            po2 = [];
                            ph2 = [];
            
                            [po,ph] = waveletSK_tfap(temp(:,n)',lowFreq:filterHz(1):highFreq,FS,filterHz(2));
                            [po2,ph2] = waveletSK_tfap(temp2(:,n)',lowFreq:filterHz(1):highFreq,FS,filterHz(2));
            
                            ph = squeeze(ph);
                            ph2 = squeeze(ph2);
            
                            ph = ph./abs(ph);
                            ph2 = ph2./abs(ph2);
                            PLV = cat(3,PLV, exp(j.*(angle(ph) - angle(ph2))));
            
                            PLV_count = PLV_count + 1;
                            clear po po2 ph ph2
                        end
            
                    else
                        h = errordlg(['Error in File: ' curFile]);
                        uiwait(h);
                    end
            
                end
            
                if PLV_count == 0
                    PLV = squeeze(abs(sum(PLV,3))./PLV_count);
                    [FileName,PathName,FilterIndex] = uiputfile('.plv');
                    if FilterIndex == 0
                        %file not save
                        errordlg('File was not saved', 'Error saving file');
                        return
                    else
                        outDir = [PathName FileName];
                        save(outDir,'PLV');
                    end
                else
                    errordlg('PLV WAS NOT COMPUTED');
                    return
                end
            
            
            end
        end

        % Button pushed function: exitButton
        function exitButton_Callback(app, event)
            % hObject    handle to exitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % myD = matlabroot;
            % myD = [myD '\TFAP\'];
            % load([myD 'numElecs_file']);
            % numCH = [];
            % save([myD 'numElecs_file'],'numCH');
            close(app.figure1)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Coherence: Cross-Electrodes';
            app.channelList = uilistbox(app.figure1);
            app.channelList.Items = {};
            app.channelList.Multiselect = 'on';
            app.channelList.Value = {};
            app.channelList2 = uilistbox(app.figure1);
            app.channelList2.Items = {};
            app.channelList2.Multiselect = 'on';
            app.channelList2.Value = {};
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'Electrode Cluster #1';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Electrode Cluster #2';
            app.analyzeCoh = uibutton(app.figure1, 'push');
            app.analyzeCoh.ButtonPushedFcn = createCallbackFcn(app, @analyzeCoh_Callback, true);
            app.analyzeCoh.Text = 'Analyze  Coherence';
            app.exitButton = uibutton(app.figure1, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton_Callback, true);
            app.exitButton.Text = 'Exit';
            app.SelectElectrodesforCoherenceAnalysisLabel = uilabel(app.figure1);
            app.SelectElectrodesforCoherenceAnalysisLabel.Text = 'Select Electrodes for Coherence  Analysis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cross_electrodes_coherence_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @cross_electrodes_coherence_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end