classdef singleTrial_prompt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        uipanel1                     matlab.ui.container.ButtonGroup
        uipanel3                     matlab.ui.container.Panel
        text6                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        erpTime1_start               matlab.ui.control.EditField
        erpTime1_end                 matlab.ui.control.EditField
        tseTime1_start               matlab.ui.control.EditField
        tseTime1_end                 matlab.ui.control.EditField
        wavPowerTime1_start          matlab.ui.control.EditField
        wavPowerTime1_end            matlab.ui.control.EditField
        wavPhaseTime1_start          matlab.ui.control.EditField
        wavPhaseTime1_end            matlab.ui.control.EditField
        text1                        matlab.ui.control.Label
        text2                        matlab.ui.control.Label
        text3                        matlab.ui.control.Label
        text4                        matlab.ui.control.Label
        panelTime2                   matlab.ui.container.Panel
        text12                       matlab.ui.control.Label
        text11                       matlab.ui.control.Label
        text9                        matlab.ui.control.Label
        wavPhaseTime2_end            matlab.ui.control.EditField
        wavPhaseTime2_start          matlab.ui.control.EditField
        wavPowerTime2_end            matlab.ui.control.EditField
        wavPowerTime2_start          matlab.ui.control.EditField
        tseTime2_end                 matlab.ui.control.EditField
        tseTime2_start               matlab.ui.control.EditField
        text8                        matlab.ui.control.Label
        text7                        matlab.ui.control.Label
        text10                       matlab.ui.control.Label
        erpTime2_start               matlab.ui.control.EditField
        erpTime2_end                 matlab.ui.control.EditField
        erpCheck                     matlab.ui.control.CheckBox
        uipanel5                     matlab.ui.container.Panel
        text16                       matlab.ui.control.Label
        erpChannel                   matlab.ui.control.EditField
        tseChannel                   matlab.ui.control.EditField
        wavPowerChannel              matlab.ui.control.EditField
        wavPhaseChannel              matlab.ui.control.EditField
        text15                       matlab.ui.control.Label
        text14                       matlab.ui.control.Label
        uipanel7                     matlab.ui.container.Panel
        text26                       matlab.ui.control.Label
        text25                       matlab.ui.control.Label
        text27                       matlab.ui.control.Label
        wavPowerFreq_start           matlab.ui.control.EditField
        wavPowerFreq_end             matlab.ui.control.EditField
        wavPhaseFreq_start           matlab.ui.control.EditField
        wavPhaseFreq_end             matlab.ui.control.EditField
        text24                       matlab.ui.control.Label
        text23                       matlab.ui.control.Label
        text22                       matlab.ui.control.Label
        text28                       matlab.ui.control.Label
        text29                       matlab.ui.control.Label
        text30                       matlab.ui.control.Label
        text31                       matlab.ui.control.Label
        tseCheck                     matlab.ui.control.CheckBox
        wavPowerCheck                matlab.ui.control.CheckBox
        wavPhaseCheck                matlab.ui.control.CheckBox
        behavCheck                   matlab.ui.control.CheckBox
        rtCheck                      matlab.ui.control.CheckBox
        text32                       matlab.ui.control.Label
        buttonAnalyze                matlab.ui.control.Button
        exitButton                   matlab.ui.control.Button
        text33                       matlab.ui.control.Label
        splitBin_edit                matlab.ui.control.EditField
        SingleTrialAnalysisGUILabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function resetVariables(app)
            
            erpOption = [];
            ERPtime1_start = [];
            ERPtime2_start = [];
            ERPtime1_end = [];
            ERPtime2_end = [];
            ERPchannels = [];
            tseOption = [];
            TSEtime1_start = [];
            TSEtime2_start = [];
            TSEtime1_end = [];
            TSEtime2_end = [];
            wavPowerOption = [];
            WPowerTime1_start = [];
            WPowerTime2_start = [];
            WPowerTime1_end = [];
            WPowerTime2_end = [];
            WPowerFreq_start = [];
            WPowerFreq_end = [];
            WPowerChannels = [];
            wavPhaseOption = [];
            WPhaseTime1_start = [];
            WPhaseTime2_start = [];
            WPhaseTime1_end = [];
            WPhaseTime2_end = [];
            WPhaseFreq_start = [];
            WPhaseFreq_end = [];
            WPhaseChannels = [];
            splitBinNumber = [];
            behavOption = [];
            rtOption = [];
            triggersTrg = {};
             myDir = matlabroot;
             save( [myDir '\TFAP\singleTrial_data'], 'erpOption', 'ERPtime1_start', 'ERPtime2_start', 'ERPtime1_end', 'ERPtime2_end', 'ERPchannels', 'tseOption', 'TSEtime1_start', 'TSEtime2_start', ...
                 'TSEtime1_end', 'TSEtime2_end', 'wavPowerOption', 'WPowerTime1_start', 'WPowerTime2_start', 'WPowerTime1_end', 'WPowerTime2_end', 'WPowerFreq_start', ...
                 'WPowerFreq_end', 'WPowerChannels', 'wavPhaseOption', 'WPhaseTime1_start', 'WPhaseTime2_start', 'WPhaseTime1_end', 'WPhaseTime2_end', 'WPhaseFreq_start', ...
                'WPhaseFreq_end', 'WPhaseChannels', 'behavOption', 'rtOption', 'splitBinNumber', 'triggersTrg');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function singleTrial_prompt_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to singleTrial_prompt (see VARARGIN)
%             
%             % Choose default command line output for singleTrial_prompt
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes singleTrial_prompt wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             %Reset the variables
%             resetVariables();
        end

        % Button pushed function: buttonAnalyze
        function buttonAnalyze_Callback(app, event)
            % hObject    handle to buttonAnalyze (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Reset the variables
            resetVariables();
            myDir = matlabroot;
            myDir = [myDir '\TFAP\'];
            load([myDir 'singleTrial_data.mat']);
            
            if get(app.erpCheck,'value') == 1
                erpOption = 1;
            
                temp = get(app.erpTime1_start,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Time Value for ERP','Error ERP Time');
                    return
                else
                    ERPtime1_start = str2num(temp);
                    ERPtime2_start = str2num(get(app.erpTime2_start,'string'));
                end
            
                temp = get(app.erpTime1_end,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Time Value for ERP','Error ERP Time');
                    return
                else
                    ERPtime1_end = str2num(temp);
                    ERPtime2_end = str2num(get(app.erpTime2_end,'string'));
                end
            
                %channel
                temp = get(app.erpChannel,'string');
                temp = str2num(temp);
                if isempty(temp) | find(temp <= 0)
                    errordlg('Input a Valid ERP Channel numbers','Error ERP Channels');
                    return
                else
                    ERPchannels = temp;
                end
            
            else
            end
            
            if get(app.tseCheck,'value') == 1
            
                tseOption = 1;
            
                temp = get(app.tseTime1_start,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Time Value for TSE','Error TSE Time');
                    return
                else
                    TSEtime1_start = str2num(temp);
                    TSEtime2_start = str2num(get(app.tseTime2_start,'string'));
                end
            
                temp = get(app.tseTime1_end,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Time Value for TSE','Error TSE Time');
                    return
                else
                    TSEtime1_end = str2num(temp);
                    TSEtime2_end = str2num(get(app.tseTime2_end,'string'));
                end
            
                %channel
                temp = get(app.tseChannel,'string');
                temp = str2num(temp);
                if isempty(temp) | find(temp <= 0)
                    errordlg('Input a Valid TSE Channel numbers','Error TSE Channels');
                    return
                else
                    TSEchannels = temp;
                end
            else
            end
            
            
            if get(app.wavPowerCheck,'value') == 1
            
                wavPowerOption = 1;
            
                temp = get(app.wavPowerTime1_start,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Time Value for Wavelet Power','Error Wavelet Power Time');
                    return
                else
                    WPowerTime1_start = str2num(temp);
                    WPowerTime2_start = str2num(get(app.wavPowerTime2_start,'string'));
                end
            
                temp = get(app.wavPowerTime1_end,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Time Value for Wavelet Power','Error Wavelet Power Time');
                    return
                else
                    WPowerTime1_end = str2num(temp);
                    WPowerTime2_end = str2num(get(app.wavPowerTime2_start,'string'));
                end
            
                temp = get(app.wavPowerFreq_start,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid Start Frequency Value for Wavelet Power','Error Wavelet Power Frequency');
                    return
                else
                    WPowerFreq_start = str2num(temp);
                end
            
                temp = get(app.wavPowerFreq_end,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid End Frequency Value for Wavelet Power','Error Wavelet Power Frequency');
                    return
                else
                    WPowerFreq_end = str2num(temp);
                end
            
                 %channel
                temp = get(app.wavPowerChannel,'string');
                temp = str2num(temp);
                if isempty(temp) | find(temp <= 0)
                    errordlg('Input a Valid Wavelet Power Channel numbers','Error Wavelet Power Channels');
                    return
                else
                    WPowerChannels = temp;
                end
            else
            end
            
            if get(app.wavPhaseCheck,'value') == 1
                wavPhaseOption = 1;
            
                temp = get(app.wavPhaseTime1_start,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Time Value for Wavelet Phase','Error Wavelet Phase Time');
                    return
                else
                    WPhaseTime1_start = str2num(temp);
                    WPhaseTime2_start = str2num(get(app.wavPhaseTime2_start,'string'));
                end
            
                temp = get(app.wavPhaseTime1_end,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Time Value for Wavelet Phase','Error Wavelet Phase Time');
                    return
                else
                    WPhaseTime1_end = str2num(temp);
                    WPhaseTime2_end = str2num(get(app.wavPhaseTime2_end,'string'));
                end
            
                temp = get(app.wavPhaseFreq_start,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid Start Frequency Value for Wavelet Phase','Error Wavelet Phase Frequency');
                    return
                else
                    WPhaseFreq_start = str2num(temp);
                end
            
                temp = get(app.wavPhaseFreq_end,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid End Frequency Value for Wavelet Phase','Error Wavelet Phase Frequency');
                    return
                else
                    WPhaseFreq_end = str2num(temp);
                end
            
                 %channel
                temp = get(app.wavPhaseChannel,'string');
                temp = str2num(temp);
                if isempty(temp) | find(temp <= 0)
                    errordlg('Input a Valid Wavelet Phase Channel numbers','Error Wavelet Phase Channels');
                    return
                else
                    WPhaseChannels = temp;
                end
            
            else
            end
            
            if get(app.behavCheck,'value') == 1
                behavOption = 1;
            
                [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Target Stimuli Codes', 'multiselect','off');
                if FilterIndex == 1 %Check if user made a selection
                    curFile = [PathName FileName];
                    temp = load(curFile);
                    triggersTrg = {};
                    for i = 1 : size(temp,1)
                        junk = [];
                        for j = 1 : size(temp,2)
                            if temp(i,j) == -1
                            else
                                junk = [junk;temp(i,j)];
                            end
                        end
                        triggersTrg{i} = junk;
                    end
                    triggersTrg = triggersTrg';
                else
                    errordlg('No Target code file was selected', 'No file selected');
                    return
                end
            
            else
            end
            
            if get(app.rtCheck,'value') == 1
                rtOption = 1;
            
                if behavOption == 1
                else
                    [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Target Stimuli Codes', 'multiselect','off');
                    if FilterIndex == 1 %Check if user made a selection
                        curFile = [PathName FileName];
                        temp = load(curFile);
                        triggersTrg = {};
                        for i = 1 : size(temp,1)
                            junk = [];
                            for j = 1 : size(temp,2)
                                if temp(i,j) == -1
                                else
                                    junk = [junk;temp(i,j)];
                                end
                            end
                            triggersTrg{i} = junk;
                        end
                        triggersTrg = triggersTrg';
                    else
                        errordlg('No Target code file was selected', 'No file selected');
                        return
                    end
            
                end
            else
            end
            
            temp = get(app.splitBin_edit,'string');
            splitBinNumber = str2num(temp);
            
             myDir = matlabroot;
             save( [myDir '\TFAP\singleTrial_data'], 'erpOption', 'ERPtime1_start', 'ERPtime2_start', 'ERPtime1_end', 'ERPtime2_end', 'ERPchannels', 'tseOption', 'TSEtime1_start', 'TSEtime2_start', ...
                 'TSEtime1_end', 'TSEtime2_end', 'wavPowerOption', 'WPowerTime1_start', 'WPowerTime2_start', 'WPowerTime1_end', 'WPowerTime2_end', 'WPowerFreq_start', ...
                 'WPowerFreq_end', 'WPowerChannels', 'wavPhaseOption', 'WPhaseTime1_start', 'WPhaseTime2_start', 'WPhaseTime1_end', 'WPhaseTime2_end', 'WPhaseFreq_start', ...
                'WPhaseFreq_end', 'WPhaseChannels', 'behavOption', 'rtOption', 'splitBinNumber', 'triggersTrg');
            
            close(app.figure1)
        end

        % Button pushed function: exitButton
        function exitButton_Callback(app, event)
            % hObject    handle to exitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            button = questdlg('Cancel single trial analysis','Exit  Window','Yes','No', 'No')
            if strcmpi(button,'yes')
                close(app.figure1)
            else
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Single Trial Analysis Window';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = 'Time Window 1';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Text = 'End Time';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Text = 'Start Time';
            app.erpTime1_start = uieditfield(app.uipanel3, 'text');
            app.erpTime1_end = uieditfield(app.uipanel3, 'text');
            app.tseTime1_start = uieditfield(app.uipanel3, 'text');
            app.tseTime1_end = uieditfield(app.uipanel3, 'text');
            app.wavPowerTime1_start = uieditfield(app.uipanel3, 'text');
            app.wavPowerTime1_end = uieditfield(app.uipanel3, 'text');
            app.wavPhaseTime1_start = uieditfield(app.uipanel3, 'text');
            app.wavPhaseTime1_end = uieditfield(app.uipanel3, 'text');
            app.text1 = uilabel(app.uipanel3);
            app.text1.Text = 'N/A';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Text = 'N/A';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Text = 'N/A';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Text = 'N/A';
            app.panelTime2 = uipanel(app.uipanel1);
            app.panelTime2.Title = 'Time Window 2';
            app.text12 = uilabel(app.panelTime2);
            app.text12.Text = 'N/A';
            app.text11 = uilabel(app.panelTime2);
            app.text11.Text = 'N/A';
            app.text9 = uilabel(app.panelTime2);
            app.text9.Text = 'N/A';
            app.wavPhaseTime2_end = uieditfield(app.panelTime2, 'text');
            app.wavPhaseTime2_start = uieditfield(app.panelTime2, 'text');
            app.wavPowerTime2_end = uieditfield(app.panelTime2, 'text');
            app.wavPowerTime2_start = uieditfield(app.panelTime2, 'text');
            app.tseTime2_end = uieditfield(app.panelTime2, 'text');
            app.tseTime2_start = uieditfield(app.panelTime2, 'text');
            app.text8 = uilabel(app.panelTime2);
            app.text8.Text = 'Start Time';
            app.text7 = uilabel(app.panelTime2);
            app.text7.Text = 'End Time';
            app.text10 = uilabel(app.panelTime2);
            app.text10.Text = 'N/A';
            app.erpTime2_start = uieditfield(app.panelTime2, 'text');
            app.erpTime2_end = uieditfield(app.panelTime2, 'text');
            app.erpCheck = uicheckbox(app.uipanel1);
            app.erpCheck.Text = 'ERP';
            app.uipanel5 = uipanel(app.uipanel1);
            app.uipanel5.Title = 'Channels';
            app.text16 = uilabel(app.uipanel5);
            app.text16.Text = 'Channel Numbers';
            app.erpChannel = uieditfield(app.uipanel5, 'text');
            app.tseChannel = uieditfield(app.uipanel5, 'text');
            app.wavPowerChannel = uieditfield(app.uipanel5, 'text');
            app.wavPhaseChannel = uieditfield(app.uipanel5, 'text');
            app.text15 = uilabel(app.uipanel5);
            app.text15.Text = 'N/A';
            app.text14 = uilabel(app.uipanel5);
            app.text14.Text = 'N/A';
            app.uipanel7 = uipanel(app.uipanel1);
            app.uipanel7.Title = 'Frequencies';
            app.text26 = uilabel(app.uipanel7);
            app.text26.Text = 'End Freq';
            app.text25 = uilabel(app.uipanel7);
            app.text25.Text = 'Start Freq';
            app.text27 = uilabel(app.uipanel7);
            app.text27.Text = 'N/A';
            app.wavPowerFreq_start = uieditfield(app.uipanel7, 'text');
            app.wavPowerFreq_end = uieditfield(app.uipanel7, 'text');
            app.wavPhaseFreq_start = uieditfield(app.uipanel7, 'text');
            app.wavPhaseFreq_end = uieditfield(app.uipanel7, 'text');
            app.text24 = uilabel(app.uipanel7);
            app.text24.Text = 'N/A';
            app.text23 = uilabel(app.uipanel7);
            app.text23.Text = 'N/A';
            app.text22 = uilabel(app.uipanel7);
            app.text22.Text = 'N/A';
            app.text28 = uilabel(app.uipanel7);
            app.text28.Text = 'N/A';
            app.text29 = uilabel(app.uipanel7);
            app.text29.Text = 'N/A';
            app.text30 = uilabel(app.uipanel7);
            app.text30.Text = 'N/A';
            app.text31 = uilabel(app.uipanel7);
            app.text31.Text = 'N/A';
            app.tseCheck = uicheckbox(app.uipanel1);
            app.tseCheck.Text = 'TSE';
            app.wavPowerCheck = uicheckbox(app.uipanel1);
            app.wavPowerCheck.Text = 'Wavelet (Power)';
            app.wavPhaseCheck = uicheckbox(app.uipanel1);
            app.wavPhaseCheck.Text = 'Wavelet (Phase)';
            app.behavCheck = uicheckbox(app.uipanel1);
            app.behavCheck.Text = 'Behavior (Accuracy)';
            app.rtCheck = uicheckbox(app.uipanel1);
            app.rtCheck.Text = 'Behavior (RT)';
            app.text32 = uilabel(app.uipanel1);
            app.text32.Text = 'Measures';
            app.buttonAnalyze = uibutton(app.uipanel1, 'push');
            app.buttonAnalyze.ButtonPushedFcn = createCallbackFcn(app, @buttonAnalyze_Callback, true);
            app.buttonAnalyze.Text = 'Analyze';
            app.exitButton = uibutton(app.uipanel1, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton_Callback, true);
            app.exitButton.Text = 'Cancel';
            app.text33 = uilabel(app.uipanel1);
            app.text33.Text = 'Number of Bins:';
            app.splitBin_edit = uieditfield(app.uipanel1, 'text');
            app.SingleTrialAnalysisGUILabel = uilabel(app.figure1);
            app.SingleTrialAnalysisGUILabel.Text = 'Single Trial Analysis GUI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = singleTrial_prompt_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @singleTrial_prompt_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end