classdef statistical_data_selection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        fileSelect_buttton  matlab.ui.control.Button
        channelList         matlab.ui.control.ListBox
        text2               matlab.ui.control.Label
        minTime             matlab.ui.control.EditField
        text3               matlab.ui.control.Label
        maxTime             matlab.ui.control.EditField
        label1              matlab.ui.control.Label
        startTime           matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        endTime_text        matlab.ui.control.EditField
        endTime             matlab.ui.control.Label
        FSedit              matlab.ui.control.EditField
        text8               matlab.ui.control.Label
        startFreq_interest  matlab.ui.control.EditField
        text9               matlab.ui.control.Label
        endFreq_interest    matlab.ui.control.EditField
        text10              matlab.ui.control.Label
        exitButton          matlab.ui.control.Button
        startFreq           matlab.ui.control.EditField
        text11              matlab.ui.control.Label
        endFreq             matlab.ui.control.EditField
        text12              matlab.ui.control.Label
        uipanel1            matlab.ui.container.ButtonGroup
        timeData            matlab.ui.control.RadioButton
        freqData            matlab.ui.control.RadioButton
        timeFreqData        matlab.ui.control.RadioButton
        sampFreq            matlab.ui.control.EditField
        text14              matlab.ui.control.Label
        SelectionofDataforStatisticalTestingLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function computeData_forStatistics(app)
            c = 0;
            load('values_for_statistics.mat')
            
            if ischar(FilesOI)
                numFiles = 1;
            else
                numFiles = length(FilesOI);
                FilesOI = sort(FilesOI);
            end
            data = zeros(numFiles,1);
            
            if dataType == 1
                c = 1;
                for K = 1 : numFiles
                    if ischar(FilesOI)
                        curFile = [FilePath FilesOI];
                    else
                        curFile = [FilePath FilesOI{K}];
                    end
            
                    load(curFile);
                    if K == 1
                        tEND = round(size(avg_ERP,1)*1000/myFS) + epochStart;
                        t = epochStartVal:1000/myFS:tEND;
            
                        if t > size(avg_ERP,1)
                            t(size(avg_ERP,1)+1:end) = [];
                        else
                        end
            
                        TOI = find(t >= startTimeVal & t <= endTimeVal);
                    else
                    end
            
                    temp = avg_ERP(TOI,CHOI);
                    data(K) = mean(temp(:));
                    clear avg_ERP
                end
            
            elseif dataType == 2
                c = 1;
                for K = 1 : numFiles
                    if ischar(FilesOI)
                        curFile = [FilePath FilesOI];
                    else
                        curFile = [FilePath FilesOI{K}];
                    end
            
                    load(curFile);
                    if K == 1
                        fEND = round(size(avg_FFT,1)*myFS_HZ);
                        f = freqInitial:myFS_HZ:fEND;
            
                        if f > size(avg_FFT,1)
                            f(size(avg_FFT,1)+1:end) = [];
                        else
                        end
            
                        FOI = find(f >= startFreqVal & f <= endFreqVal);
            
                    else
                    end
            
                    temp = avg_FFT(FOI,CHOI);
                    data(K) = mean(temp(:));
                    clear avg_FFT
                end
            
            else
                c = 1;
                for K = 1 : numFiles
                    if ischar(FilesOI)
                        curFile = [FilePath FilesOI];
                    else
                        curFile = [FilePath FilesOI{K}];
                    end
            
                    load(curFile);
                    if K == 1
                        fEND = round(size(avgWav,2)*myFS_HZ);
                        f = freqInitial:myFS_HZ:fEND;
            
                        if f > size(avgWav,2)
                            f(size(avgWav,2)+1:end) = [];
                        else
                        end
            
                        FOI = find(f >= startFreqVal & f <= endFreqVal);
            
                        tEND = round(size(avgWav,3)*1000/myFS) + epochStart;
                        t = epochStartVal:1000/myFS:tEND;
            
                        if t > size(avgWav,3)
                            t(size(avgWav,3)+1:end) = [];
                        else
                        end
            
                        TOI = find(t >= startTimeVal & t <= endTimeVal);
                    else
                    end
            
                    temp = avgWav(CHOI,FOI,TOI);
                    data(K) = abs(mean(temp(:)));
            
                    clear avgWav
                end
            
            end
            load('statisticsData.mat');
            dataStats = cat(2,dataStats,data);
            save('statisticsData.mat','dataStats');
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function statistical_data_selection_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to statistical_data_selection (see VARARGIN)
%             
%             % Choose default command line output for statistical_data_selection
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes statistical_data_selection wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             [FileName,PathName,FilterIndex] = uigetfile('*.mat','Select Electrode Coordinate File', 'multiselect','off');
%             
%             if FilterIndex ~= 0 %Check if user made a selection
%                 load([PathName FileName]);
%             else
%                 errordlg('Error selecting electrode coordinate file');
%                 return
%             end
%             channelList = elecLabels;
%             set(app.channelList,'String', channelList, 'value', 1);
%             set(app.channelList,'HorizontalAlignment', 'center');
%             
%             load('values_for_statistics.mat');
%             FilePath = '';
%             FilesOI = {};
%             myFS = [];
%             startEpoch = [];
%             epochStartVal = [];
%             startTimeVal = [];
%             endTimeVal = [];
%             startFreqVal = [];
%             endFreqVal = [];
%             CHOI = [];
%             freqInitial = [];
%             dataType = [];
%             myFS_HZ = [];
%             save('values_for_statistics.mat', 'FilePath','FilesOI','myFS', 'myFS_HZ', 'epochStartVal',...
%                 'startTimeVal','endTimeVal','freqInitial','startFreqVal','endFreqVal','CHOI','dataType');
%             
%             dataStats = [];
%             save('statisticsData.mat','dataStats');
        end

        % Button pushed function: exitButton
        function exitButton_Callback(app, event)
            % hObject    handle to exitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            button = questdlg('Exit The Program','Exit Program');
            if strcmpi(button,'yes')
                close statistical_data_selection
                return
            else
            end
        end

        % Button pushed function: fileSelect_buttton
        function fileSelect_buttton_Callback(app, event)
            % hObject    handle to fileSelect_buttton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataStats = [];
            save('statisticsData.mat','dataStats');
            buttonTwo = 'yes';
            G = 1;
            while strcmpi(buttonTwo,'yes')
            
                load('values_for_statistics.mat');
            
                dataType = 0;
                if get(app.timeData,'Value')
                    dataType = 1;
                elseif get(app.freqData,'Value')
                    dataType = 2;
                else
                    dataType = 3;
                end;
            
                CHOI = get(app.channelList,'value');
                labelChannels = get(app.channelList,'String');
            
                if dataType == 1
            
                    temp = get(app.FSedit,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Sampling Rate Time Number','Error Sampling Rate Time');
                        return
                    else
                        myFS = str2num(temp);
                    end
            
                    temp = get(app.minTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Epoch Number','Error Start Epoch Number');
                        return
                    else
                        epochStartVal = str2num(temp);
                    end
            
                    temp = get(app.maxTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Epoch Number','Error End Epoch Number');
                        return
                    else
                        endEpoch = str2num(temp);
                    end
            
                    temp = get(app.startTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Time of Interest Number','Error Start Time of Interest Number');
                        return
                    else
                        startTimeVal = str2num(temp);
                    end
            
                    temp = get(app.endTime_text,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Time of Interest Number','Error End Time of Interest Number');
                        return
                    else
                        endTimeVal = str2num(temp);
                    end
            
                    myCh = '';
                    for K = 1 : length(CHOI)
                        if K == length(CHOI)
                            myCh = [myCh labelChannels{CHOI(K)}];
                        else
                            myCh = [myCh labelChannels{CHOI(K)} ', '];
                        end
                    end
            
                    Y = {['Channels: ' myCh]
                        ''
                        ['Times: ' num2str(startTimeVal) ' to ' num2str(endTimeVal) ' ms']
                        ''};
            
                    button = questdlg(Y);
                    if strcmpi(button, 'yes')
            
                        [FilesOI,FilePath,FilterIndex] = uigetfile('*.mat','Select Files for Statistics', 'multiselect','on');
                        save('values_for_statistics.mat', 'FilePath', 'FilesOI','myFS','myFS_HZ', 'epochStartVal',...
                            'startTimeVal','endTimeVal','freqInitial','startFreqVal','endFreqVal','CHOI', 'dataType');
            
                        computeData_forStatistics
            
                    else
                        return
                    end
            
                elseif dataType == 2
            
                    temp = get(app.sampFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Sampling Rate Hz Number','Error Sampling Rate Hz');
                        return
                    else
                        myFS_HZ = str2num(temp);
                    end
            
                    temp = get(app.startFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Frequency Number','Error Start Frequency Number');
                        return
                    else
                        freqInitial = str2num(temp);
                    end
            
                    temp = get(app.endFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Frequency Number','Error End Frequency Number');
                        return
                    else
                        freqEndMe = str2num(temp);
                    end
            
                    temp = get(app.startFreq_interest,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Frequency of Interest Number','Error Start Frequency Interest Number');
                        return
                    else
                        startFreqVal = str2num(temp);
                    end
            
                    temp = get(app.endFreq_interest,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Frequency of Interest Number','Error End Frequency Interest Number');
                        return
                    else
                        endFreqVal = str2num(temp);
                    end
            
            
                    myCh = '';
                    for K = 1 : length(CHOI)
                        if K == length(CHOI)
                            myCh = [myCh labelChannels{CHOI(K)}];
                        else
                            myCh = [myCh labelChannels{CHOI(K)} ', '];
                        end
                    end
            
                    Y = {['Channels: ' myCh]
                        ''
                        ['Frequency(ies): ' num2str(startFreqVal) ' to ' num2str(endFreqVal) ' HZ']
                        ''};
            
                    button = questdlg(Y);
                    if strcmpi(button, 'yes')
            
                        [FilesOI,FilePath,FilterIndex] = uigetfile('*.mat','Select Files for Statistics', 'multiselect','on');
                        save('values_for_statistics.mat', 'FilePath', 'FilesOI','myFS','myFS_HZ', 'epochStartVal',...
                            'startTimeVal','endTimeVal','freqInitial','startFreqVal','endFreqVal','CHOI', 'dataType');
            
                        computeData_forStatistics
            
                    else
                        return
                    end
            
                else
            
                    temp = get(app.FSedit,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Sampling Rate Time Number','Error Sampling Rate Time');
                        return
                    else
                        myFS = str2num(temp);
                    end
            
                    temp = get(app.sampFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Sampling Rate Hz Number','Error Sampling Rate Hz');
                        return
                    else
                        myFS_HZ = str2num(temp);
                    end
            
                    temp = get(app.minTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Epoch Number','Error Start Epoch Number');
                        return
                    else
                        epochStartVal = str2num(temp);
                    end
            
                    temp = get(app.maxTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Epoch Number','Error End Epoch Number');
                        return
                    else
                        endEpoch = str2num(temp);
                    end
            
                    temp = get(app.startTime,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Time of Interest Number','Error Start Time of Interest Number');
                        return
                    else
                        startTimeVal = str2num(temp);
                    end
            
                    temp = get(app.endTime_text,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Time of Interest Number','Error End Time of Interest Number');
                        return
                    else
                        endTimeVal = str2num(temp);
                    end
            
                    temp = get(app.startFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Frequency Number','Error Start Frequency Number');
                        return
                    else
                        freqInitial = str2num(temp);
                    end
            
                    temp = get(app.endFreq,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Frequency Number','Error End Frequency Number');
                        return
                    else
                        freqEndMe = str2num(temp);
                    end
            
                    temp = get(app.startFreq_interest,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid Start Frequency of Interest Number','Error Start Frequency Interest Number');
                        return
                    else
                        startFreqVal = str2num(temp);
                    end
            
                    temp = get(app.endFreq_interest,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid End Frequency of Interest Number','Error End Frequency Interest Number');
                        return
                    else
                        endFreqVal = str2num(temp);
                    end
            
            
                    myCh = '';
                    for K = 1 : length(CHOI)
                        if K == length(CHOI)
                            myCh = [myCh labelChannels{CHOI(K)}];
                        else
                            myCh = [myCh labelChannels{CHOI(K)} ', '];
                        end
                    end
            
                    Y = {['Channels: ' myCh]
                        ''
                        ['Times: ' num2str(startTimeVal) ' to ' num2str(endTimeVal) ' HZ']
                        ''
                        ['Frequency(ies): ' num2str(startFreqVal) ' to ' num2str(endFreqVal) ' ms']
                        ''};
            
                    button = questdlg(Y);
                    if strcmpi(button, 'yes')
            
                        [FilesOI,FilePath,FilterIndex] = uigetfile('*.mat','Select Files for Statistics', 'multiselect','on');
                        save('values_for_statistics.mat', 'FilePath', 'FilesOI','myFS','myFS_HZ', 'epochStartVal',...
                            'startTimeVal','endTimeVal','freqInitial','startFreqVal','endFreqVal','CHOI', 'dataType');
            
                        computeData_forStatistics
            
                    else
                        return
                    end
            
                end
            
                G = 2;
                buttonTwo = questdlg('Calculate Values for Another Condition','Add Values');
            end
            
            load('statisticsData.mat');
            [outName,outPath] = uiputfile('.txt','Save Output File');
            dlmwrite([outPath outName],dataStats,'delimiter','\t');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'statistical_data_selection';
            app.fileSelect_buttton = uibutton(app.figure1, 'push');
            app.fileSelect_buttton.ButtonPushedFcn = createCallbackFcn(app, @fileSelect_buttton_Callback, true);
            app.fileSelect_buttton.Text = 'Select Files For Statistics';
            app.channelList = uilistbox(app.figure1);
            app.channelList.Items = {};
            app.channelList.Multiselect = 'on';
            app.channelList.Value = {};
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Channels';
            app.minTime = uieditfield(app.figure1, 'text');
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Start of Epoch';
            app.maxTime = uieditfield(app.figure1, 'text');
            app.label1 = uilabel(app.figure1);
            app.label1.Text = 'End of Epoch';
            app.startTime = uieditfield(app.figure1, 'text');
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Start Time of Interest';
            app.endTime_text = uieditfield(app.figure1, 'text');
            app.endTime = uilabel(app.figure1);
            app.endTime.Text = 'End Time of Interest';
            app.FSedit = uieditfield(app.figure1, 'text');
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Sampling Rate (Time Data)';
            app.startFreq_interest = uieditfield(app.figure1, 'text');
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Start Frequency of Interest';
            app.endFreq_interest = uieditfield(app.figure1, 'text');
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'End Frequency of Interest';
            app.exitButton = uibutton(app.figure1, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton_Callback, true);
            app.exitButton.Text = 'Exit';
            app.startFreq = uieditfield(app.figure1, 'text');
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'Start Frequency';
            app.endFreq = uieditfield(app.figure1, 'text');
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'End Frequency';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Type Data';
            app.timeData = uiradiobutton(app.uipanel1);
            app.timeData.Text = 'Time Domain';
            app.timeData.Value = true;
            app.freqData = uiradiobutton(app.uipanel1);
            app.freqData.Text = 'Frequency Domain';
            app.timeFreqData = uiradiobutton(app.uipanel1);
            app.timeFreqData.Text = 'Time-Frequency Domain';
            app.sampFreq = uieditfield(app.figure1, 'text');
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'Resolution (Freq Data)';
            app.SelectionofDataforStatisticalTestingLabel = uilabel(app.figure1);
            app.SelectionofDataforStatisticalTestingLabel.Text = 'Selection of Data for Statistical Testing ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = statistical_data_selection_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @statistical_data_selection_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end