classdef timeFreq_GUI_new_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        runAnalysis_button           matlab.ui.control.Button
        exitButton                   matlab.ui.control.Button
        uipanel11                    matlab.ui.container.Panel
        uipanel21                    matlab.ui.container.Panel
        binFreq_text                 matlab.ui.control.Label
        text10                       matlab.ui.control.Label
        text11                       matlab.ui.control.Label
        text27                       matlab.ui.control.Label
        Lowest_FOI_edit              matlab.ui.control.EditField
        Highest_FOI_edit             matlab.ui.control.EditField
        binFreq_edit                 matlab.ui.control.EditField
        editWavScaling               matlab.ui.control.EditField
        uipanel22                    matlab.ui.container.Panel
        text29                       matlab.ui.control.Label
        butterCoeff_edit             matlab.ui.control.EditField
        text30                       matlab.ui.control.Label
        text31                       matlab.ui.control.Label
        lowFreq_tevo_edit            matlab.ui.control.EditField
        highFreq_tevo_edit           matlab.ui.control.EditField
        uipanel12                    matlab.ui.container.Panel
        checkERP                     matlab.ui.control.CheckBox
        checkFFT                     matlab.ui.control.CheckBox
        checkTempEvo                 matlab.ui.control.CheckBox
        checkWavelet                 matlab.ui.control.CheckBox
        checkWavelet_phase           matlab.ui.control.CheckBox
        CrossElecCoh_menu            matlab.ui.control.CheckBox
        singleTrial_analysis         matlab.ui.control.CheckBox
        uipanel14                    matlab.ui.container.Panel
        uipanel16                    matlab.ui.container.Panel
        text2                        matlab.ui.control.Label
        FS_edit                      matlab.ui.control.EditField
        text4                        matlab.ui.control.Label
        downsample_edit              matlab.ui.control.EditField
        text6                        matlab.ui.control.Label
        Start_Epoch_edit             matlab.ui.control.EditField
        text7                        matlab.ui.control.Label
        End_Epoch_edit               matlab.ui.control.EditField
        text28                       matlab.ui.control.Label
        Start_Baseline_edit          matlab.ui.control.EditField
        text9                        matlab.ui.control.Label
        End_baseline_edit            matlab.ui.control.EditField
        uipanel17                    matlab.ui.container.Panel
        uipanel19                    matlab.ui.container.Panel
        text19                       matlab.ui.control.Label
        Thrsh_amp_eye_movement_edit  matlab.ui.control.EditField
        text20                       matlab.ui.control.Label
        Time_window_eye_movements_datapts_edit  matlab.ui.control.EditField
        text32                       matlab.ui.control.Label
        Art_rej_thrsh_edit           matlab.ui.control.EditField
        uipanel24                    matlab.ui.container.ButtonGroup
        stimStandard_button          matlab.ui.control.RadioButton
        stimTarget_button            matlab.ui.control.RadioButton
        uipanel4                     matlab.ui.container.ButtonGroup
        lowPass_filter_button        matlab.ui.control.EditField
        highPass_filter_button       matlab.ui.control.EditField
        bandPass_on_button           matlab.ui.control.RadioButton
        bandPass_off_button          matlab.ui.control.RadioButton
        text22                       matlab.ui.control.Label
        text23                       matlab.ui.control.Label
        uipanel18                    matlab.ui.container.Panel
        uipanel6                     matlab.ui.container.ButtonGroup
        Rereference_Single_Channel   matlab.ui.control.RadioButton
        No_ReReference               matlab.ui.control.RadioButton
        ReReference_to_average       matlab.ui.control.RadioButton
        singleCh_ref_edit            matlab.ui.control.EditField
        uipanel1                     matlab.ui.container.ButtonGroup
        noInterp_edit                matlab.ui.control.RadioButton
        choose_spline_edit           matlab.ui.control.RadioButton
        uipanel8                     matlab.ui.container.ButtonGroup
        YesloadBD_CH_List            matlab.ui.control.RadioButton
        NoloadBD_CH_List             matlab.ui.control.RadioButton
        text13                       matlab.ui.control.Label
        text18                       matlab.ui.control.Label
        choose_ave_edit              matlab.ui.control.RadioButton
        choose_weigted_ave_edit      matlab.ui.control.RadioButton
        Pctge_pts_to_remove_edit     matlab.ui.control.EditField
        StD_thresh_for_interp_edit   matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function Elec_Loc_File_edit_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Elec_Loc_File_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function No_of_channels_edit_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to No_of_channels_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Outgoing_Directory_edit_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Outgoing_Directory_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function changeStructure_button_Callback(app, hObject, eventdata, handles)
            % hObject    handle to changeStructure_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            reorganizeFile_structure
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit25_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Start_Baseline_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit29_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Art_rej_thrsh_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function makeGrpAvg_Callback(app, hObject, eventdata, handles)
            % hObject    handle to makeGrpAvg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            makeGrp_avg_new
        end
        
        function plotData_Callback(app, hObject, eventdata, handles)
            % hObject    handle to plotData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            plotData_GUI_new
        end
        
        function resetVariables(app)
            
            typeAnalysis = [];
            FS = [];
            DS = [];
            numChannels = [];
            startEpoch = [];
            endEpoch = [];
            startBaseline = [];
            endBaseline = [];
            lowFreq = [];
            highFreq = [];
            binFreq = [];
            bandpassData = [];
            lowPassFilter = [];
            highPassFilter = [];
            refData = [];
            refChannel = [];
            filename_Channels = '';
            STD_interpolation = [];
            InterpMethod = [];
            removePct = [];
            removeEyes = [];
            thres_eyes = [];
            window_eyes = [];
            artRej_Threshold = [];
            responseButton_code = [];
            triggers = [];
            triggerLabels = {};
            badCh_list = [];
            waveletwidth = [];
            butterCoeff = [];
            lowFreq_butter = [];
            highFreq_butter = [];
            mySubjs = {''};
            singleTrial_data = [];
            responseFree = [];
            CHOI = [];
            CHOI2 = [];
            
            
            myDir = matlabroot;
            myDir = [myDir '\TFAP\'];
            save([myDir 'parameterFiles_TimeFreq_Analysis.mat'], 'typeAnalysis', 'FS','DS','numChannels','startEpoch', ...
                'endEpoch', 'startBaseline', 'endBaseline', 'lowFreq', 'highFreq','waveletwidth','binFreq', 'butterCoeff', 'lowFreq_butter', 'highFreq_butter', 'bandpassData', ...
                'lowPassFilter', 'highPassFilter', 'refData', 'refChannel', 'filename_Channels', 'mySubjs', ...
                'STD_interpolation', 'InterpMethod', 'removePct', 'removeEyes','thres_eyes', 'window_eyes', ...
                'artRej_Threshold', 'responseButton_code','triggers', 'triggerLabels', 'badCh_list', 'singleTrial_data', 'responseFree', 'CHOI', 'CHOI2');
        end
        
        function statisticData_button_Callback(app, hObject, eventdata, handles)
            % hObject    handle to statisticData_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            statistical_data_selection
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function timeFreq_GUI_new_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to timeFreq_GUI_new (see VARARGIN)
%             
%             % Choose default command line output for timeFreq_GUI_new
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes timeFreq_GUI_new wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             %reset variables
%             resetVariables()
        end

        % Button pushed function: exitButton
        function exitButton_Callback(app, event)
            % hObject    handle to exitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            button = questdlg('Exit The Program','Exit Analysis Program');
            if strcmpi(button,'yes')
                close(app.figure1)
                return
            else
            end
        end

        % Button pushed function: runAnalysis_button
        function runAnalysis_button_Callback(app, event)
            % hObject    handle to runAnalysis_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            resetVariables()
            myDir = matlabroot;
            myDir = [myDir '\TFAP\'];
            load([myDir 'parameterFiles_TimeFreq_Analysis.mat']);
            
            if get(app.checkERP,'value') == 0 & get(app.checkFFT,'value') == 0 & ...
                    get(app.checkTempEvo,'value') == 0 & get(app.checkWavelet,'value') == 0 & ...
                    get(app.checkWavelet_phase,'value') == 0 & get(app.singleTrial_analysis,'Value') == 0 ...
                    & get(app.CrossElecCoh_menu,'Value') == 0
            
                errordlg('Select a type of analysis to run','Analysis Selection Error');
            
            else
            
                myDir = matlabroot;
                myDir = [myDir '\TFAP\'];
                load([myDir 'parameterFiles_TimeFreq_Analysis.mat']);
            
                typeAnalysis = [get(app.checkERP,'value') get(app.checkFFT,'value') get(app.checkTempEvo,'value') ...
                    get(app.checkWavelet,'value') get(app.checkWavelet_phase,'value') ...
                    get(app.CrossElecCoh_menu,'Value') get(app.singleTrial_analysis,'Value')];
            
                if get(app.singleTrial_analysis,'Value')
                    singleTrial_data = 1;
                    waitfor(singleTrial_prompt);
                else
                end
            
                if get(app.CrossElecCoh_menu,'Value')
                    waitfor(PLV_pick);
                    load([myDir 'channels_PLV.mat']);
                    CHOI = chPLV;
                    CHOI2 = chPLV2;
                else
                end
            
            
                temp = get(app.FS_edit,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid Sampling Rate','Error Sampling Rate');
                    return
                else
                    FS = str2num(temp);
                end
            
                temp = get(app.downsample_edit,'string');
                if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                    errordlg('Input a Valid Downsample Rate','Error Downsample Rate');
                    return
                else
                    DS = str2num(temp);
                end
            
                temp = get(app.Start_Epoch_edit,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Epoch Number','Error Start Epoch Number');
                    return
                else
                    startEpoch = str2num(temp);
                end
            
                temp = get(app.End_Epoch_edit,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Epoch Number','Error End Epoch Number');
                    return
                else
                    endEpoch = str2num(temp);
                end
            
                temp = get(app.Start_Baseline_edit,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Start Baseline Number','Error Start Baseline Number');
                    return
                else
                    startBaseline = str2num(temp);
                end
            
                temp = get(app.End_baseline_edit,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid End Baseline Number','Error End Baseline Number');
                    return
                else
                    endBaseline = str2num(temp);
                end
            
                temp = get(app.Art_rej_thrsh_edit,'string');
                if isempty(temp) || isempty(str2num(temp))
                    errordlg('Input a Valid Artifact Rejection Number','Error Artifact Rejection');
                    return
                else
                    artRej_Threshold = str2num(temp);
                end
            
                if get(app.eyeRej_Yes, 'value') == 1
                    removeEyes = 1;
            
                    temp = get(app.Thrsh_amp_eye_movement_edit,'string');
                    if isempty(temp) || isempty(str2num(temp))
                        errordlg('Input a Valid for Threshold Amplitude for Eye Mov','Error Eye Mov');
                        return
                    else
                        thres_eyes = str2num(temp);
                    end
            
                    temp = get(app.Time_window_eye_movements_datapts_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0
                        errordlg('Input a Valid Time Window for Eye Mov','Error Eye Mov');
                        return
                    else
                        window_eyes = str2num(temp);
                    end
            
                else
                    removeEyes = 0;
                end
            
                if get(app.stimStandard_button, 'value') == 1
                    responseFree = 1;
                else
                    responseFree = 0;
                end
            
                if get(app.bandPass_off_button,'Value')
                    bandpassData = 0;
                else
                    bandpassData = 1;
                    temp = get(app.lowPass_filter_button,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0
                        errordlg('Input a Valid Low Pass Filter Number','Error Low-Pass Filter');
                        return
                    else
                        lowPassFilter = str2num(temp);
                    end
            
                    temp = get(app.highPass_filter_button,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0 || ...
                            lowPassFilter > str2num(temp)
                        errordlg('Input a Valid High Pass Filter Number','Error High-Pass Filter');
                        return
                    else
                        highPassFilter = str2num(temp);
                    end
                end
            
                if get(app.No_ReReference,'Value')
                    refData = 0;
                else
            
                    if get(app.Rereference_Single_Channel,'Value')
                        refData = 1;
                        temp = get(app.singleCh_ref_edit,'string');
                        if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                            errordlg('Input a Valid Single Channel Reference Number','Error Single Channel Reference');
                            return
                        else
                            refChannel = str2num(temp);
                        end
            
                    elseif get(app.ReReference_to_average,'Value')
                        refData = 2;
                    else
                        refData = 0;
                    end
            
                end
            
                if get(app.noInterp_edit,'Value')
                    InterpMethod = 0;
                else
            
                    if get(app.choose_spline_edit,'Value')
                        InterpMethod = 1;
                        temp = get(app.Pctge_pts_to_remove_edit,'string');
            
                        if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0
                            errordlg('Input a Valid % Removal Points','Error % Points Removal');
                            return
                        else
                            removePct = str2num(temp);
                        end
            
                    elseif get(app.choose_weigted_ave_edit,'Value')
                        InterpMethod = 2;
                    elseif get(app.choose_ave_edit,'Value')
                        InterpMethod = 3;
                    else
                        InterpMethod = 0;
                    end
            
                    temp = get(app.StD_thresh_for_interp_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0
                        errordlg('Input a Valid Std Dev for Interpolation','Error Std Dev for Interpolation');
                        return
                    else
                        STD_interpolation = str2num(temp);
                    end
                end
            
                if get(app.checkWavelet,'value') == 1 | singleTrial_data == 1 ...
                        get(app.checkWavelet_phase,'value') == 1 | get(app.CrossElecCoh_menu,'value') == 1
            
                    temp = get(app.Lowest_FOI_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                        errordlg('Input a Valid Low Frequency Value','Error Freq definitions');
                        return
                    else
                        lowFreq = str2num(temp);
                    end
            
                    temp = get(app.Highest_FOI_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0 || ...
                            lowFreq > str2num(temp)
                        errordlg('Input a Valid High Frequency Value','Error Freq definitions');
                        return
                    else
                        highFreq = str2num(temp);
                    end
            
                    temp = get(app.binFreq_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                        errordlg('Input a Valid Frequency Bin Value','Error Bin Frequencies');
                        return
                    else
                        binFreq = str2num(temp);
                    end
            
                    temp = get(app.editWavScaling,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                        errordlg('Input a Valid Wavelet Scaling Value Bin Value','Error Bin Frequencies');
                        return
                    else
                        waveletwidth = str2num(temp);
                    end
            
                else
                end
            
                if get(app.checkTempEvo,'value') == 1 | singleTrial_data == 1
                    temp = get(app.butterCoeff_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                        errordlg('Input a Valid Butterworth Coefficient Value','Error Butterworth Coefficient');
                        return
                    else
                        butterCoeff = str2num(temp);
                    end
            
                    temp = get(app.lowFreq_tevo_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) <= 0
                        errordlg('Input a Valid Low Frequency Value For Temporal Evolution','Error Freq definitions');
                        return
                    else
                        lowFreq_butter = str2num(temp);
                    end
            
                    temp = get(app.highFreq_tevo_edit,'string');
                    if isempty(temp) || isempty(str2num(temp)) || str2num(temp) < 0 || ...
                            lowFreq_butter > str2num(temp)
                        errordlg('Input a Valid High Frequency Value For Temporal Evolution','Error Freq definitions');
                        return
                    else
                        highFreq_butter = str2num(temp);
                    end
            
                else
                end
            
            
                %Select files to analyze
                [FileName,PathName,FilterIndex] = uigetfile('*.txt','Subject Loading File', 'multiselect','off');
                if FilterIndex == 1
                    mySubjs = {};
                    ss = 1;
                    fid = fopen(char([PathName FileName]));
                    while ~feof(fid)
                        mySubjs{ss} = fgetl(fid);
                        ss = ss + 1;
                    end
                    fclose(fid);
                    if mySubjs{end} == -1
                        errordlg('Subject file is empty', 'Error in subject file')
                        return
                    else
                    end
                else
                    errordlg('No subject file was selected', 'No file selected');
                    return
                end
            
                [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Trigger Code', 'multiselect','off');
                if FilterIndex == 1 %Check if user made a selection
                    curFile = [PathName FileName];
                    temp = load(curFile);
                    triggers = {};
                    for i = 1 : size(temp,1)
                        junk = [];
                        for j = 1 : size(temp,2)
                            if temp(i,j) == -1
                            else
                                junk = [junk;temp(i,j)];
                            end
                        end
                        triggers{i} = junk;
                    end
                    triggers = triggers';
                else
                    errordlg('No trigger code file was selected', 'No file selected');
                    return
                end
            
                [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Trigger Labels', 'multiselect','off');
                if FilterIndex == 1 %Check if user made a selection
                    curFile = [PathName FileName];
                    temp = textread(curFile,'%s');
                    triggerLabels = {};
                    for i = 1 : size(temp,1)
                        triggerLabels{i} = temp{i};
                    end
                    triggerLabels = triggerLabels';
                else
                    errordlg('No trigger Label file was selected', 'No file selected');
                    return
                end
            
                [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select Response Button File', 'multiselect','off');
                if FilterIndex == 1 %Check if user made a selection
                    curFile = [PathName FileName];
                    responseButton_code = load(curFile);
                else
                end
            
                [FileName,PathName,FilterIndex] = uigetfile('*.mat','Select Electrode File Locations', 'multiselect','off');
                if FilterIndex == 1 %Check if user made a selection
                    filename_Channels = [PathName FileName];
                else
                    errordlg('No Electrode File was selected', 'No file selected');
                    return
                end
            
            
                if get(app.YesloadBD_CH_List,'Value')
                    [FileName,PathName,FilterIndex] = uigetfile('*.txt','Select File for Bad Channels', 'multiselect','off');
                    if FilterIndex == 1 %Check if user made a selection
                        curFile = [PathName FileName];
                        badCh_list = load(curFile);
                    else
                        errordlg('No bad channel file was selected', 'No file selected');
                        return
                    end
                else
                    badCh_list = [];
                end
            
            
                myDir = matlabroot;
                myDir = [myDir '\TFAP\'];
                save([myDir 'parameterFiles_TimeFreq_Analysis.mat'], 'typeAnalysis', 'FS','DS','numChannels','startEpoch', ...
                    'endEpoch', 'startBaseline', 'endBaseline', 'lowFreq', 'highFreq','waveletwidth', 'binFreq', 'butterCoeff', 'lowFreq_butter', ...
                    'highFreq_butter', 'bandpassData', 'lowPassFilter', 'highPassFilter', 'refData', 'refChannel', 'filename_Channels', ...
                    'STD_interpolation', 'InterpMethod', 'removePct', 'removeEyes','thres_eyes', 'window_eyes', ...
                    'artRej_Threshold', 'responseButton_code','triggers', 'triggerLabels', 'badCh_list', 'mySubjs', 'singleTrial_data', 'responseFree', ...
                    'CHOI', 'CHOI2');
            
                h = waitbar(0,'Parameters Loaded ... Starting Analysis');
                for i = 1:2000
                    waitbar(i / 2000);
                end
                close(h)
                close(app.figure1);
                %Run analysis program
                timeFreq_analysis_new
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Run Batch Analysis';
            app.runAnalysis_button = uibutton(app.figure1, 'push');
            app.runAnalysis_button.ButtonPushedFcn = createCallbackFcn(app, @runAnalysis_button_Callback, true);
            app.runAnalysis_button.Text = 'Run Analysis';
            app.exitButton = uibutton(app.figure1, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton_Callback, true);
            app.exitButton.Text = 'Exit';
            app.uipanel11 = uipanel(app.figure1);
            app.uipanel11.Title = 'Time Frequency Analysis Parameters';
            app.uipanel21 = uipanel(app.uipanel11);
            app.uipanel21.Title = 'Wavelet Parameters';
            app.binFreq_text = uilabel(app.uipanel21);
            app.binFreq_text.Text = 'Frequency Bins (wavelet)';
            app.text10 = uilabel(app.uipanel21);
            app.text10.Text = 'Lowest Freq of Interest';
            app.text11 = uilabel(app.uipanel21);
            app.text11.Text = 'Highest Freq of Interest';
            app.text27 = uilabel(app.uipanel21);
            app.text27.Text = 'Wavelet Scaling';
            app.Lowest_FOI_edit = uieditfield(app.uipanel21, 'text');
            app.Lowest_FOI_edit.Value = '1';
            app.Highest_FOI_edit = uieditfield(app.uipanel21, 'text');
            app.Highest_FOI_edit.Value = '100';
            app.binFreq_edit = uieditfield(app.uipanel21, 'text');
            app.binFreq_edit.Value = '2';
            app.editWavScaling = uieditfield(app.uipanel21, 'text');
            app.editWavScaling.Value = '7';
            app.uipanel22 = uipanel(app.uipanel11);
            app.uipanel22.Title = 'Temporal Evolution Parameters';
            app.text29 = uilabel(app.uipanel22);
            app.text29.Text = 'Butterworth Coefficient';
            app.butterCoeff_edit = uieditfield(app.uipanel22, 'text');
            app.butterCoeff_edit.Value = '3';
            app.text30 = uilabel(app.uipanel22);
            app.text30.Text = 'Lowest Freq of Interest';
            app.text31 = uilabel(app.uipanel22);
            app.text31.Text = 'Highest Freq of Interest';
            app.lowFreq_tevo_edit = uieditfield(app.uipanel22, 'text');
            app.lowFreq_tevo_edit.Value = '1';
            app.highFreq_tevo_edit = uieditfield(app.uipanel22, 'text');
            app.highFreq_tevo_edit.Value = '100';
            app.uipanel12 = uipanel(app.figure1);
            app.uipanel12.Title = 'Type of Analysis';
            app.checkERP = uicheckbox(app.uipanel12);
            app.checkERP.Text = 'ERP';
            app.checkFFT = uicheckbox(app.uipanel12);
            app.checkFFT.Text = 'FFT';
            app.checkTempEvo = uicheckbox(app.uipanel12);
            app.checkTempEvo.Text = 'Temporal Spectrum Evolution';
            app.checkWavelet = uicheckbox(app.uipanel12);
            app.checkWavelet.Text = 'Wavelet Power';
            app.checkWavelet_phase = uicheckbox(app.uipanel12);
            app.checkWavelet_phase.Text = 'Wavelet Phase (ITC)';
            app.CrossElecCoh_menu = uicheckbox(app.uipanel12);
            app.CrossElecCoh_menu.Text = 'Cross Electrode Coherence';
            app.singleTrial_analysis = uicheckbox(app.uipanel12);
            app.singleTrial_analysis.Text = '      Save Single Trials';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'Pre-Processing Parameters';
            app.uipanel16 = uipanel(app.uipanel14);
            app.uipanel16.Title = 'Time Parameters';
            app.text2 = uilabel(app.uipanel16);
            app.text2.Text = 'Sampling Rate';
            app.FS_edit = uieditfield(app.uipanel16, 'text');
            app.FS_edit.Value = '512';
            app.text4 = uilabel(app.uipanel16);
            app.text4.Text = 'Downsample Data';
            app.downsample_edit = uieditfield(app.uipanel16, 'text');
            app.downsample_edit.Value = '1';
            app.text6 = uilabel(app.uipanel16);
            app.text6.Text = 'Start Epoch';
            app.Start_Epoch_edit = uieditfield(app.uipanel16, 'text');
            app.Start_Epoch_edit.Value = '-400';
            app.text7 = uilabel(app.uipanel16);
            app.text7.Text = 'End Epoch';
            app.End_Epoch_edit = uieditfield(app.uipanel16, 'text');
            app.End_Epoch_edit.Value = '800';
            app.text28 = uilabel(app.uipanel16);
            app.text28.Text = 'Start Baseline';
            app.Start_Baseline_edit = uieditfield(app.uipanel16, 'text');
            app.Start_Baseline_edit.Value = '-100';
            app.text9 = uilabel(app.uipanel16);
            app.text9.Text = 'End Baseline';
            app.End_baseline_edit = uieditfield(app.uipanel16, 'text');
            app.End_baseline_edit.Value = '0';
            app.uipanel17 = uipanel(app.uipanel14);
            app.uipanel17.Title = 'Artifact Parameters';
            app.uipanel19 = uipanel(app.uipanel17);
            app.uipanel19.Title = 'Channel Rejection';
            app.text19 = uilabel(app.uipanel19);
            app.text19.Text = 'Threshold Eye Movements';
            app.Thrsh_amp_eye_movement_edit = uieditfield(app.uipanel19, 'text');
            app.Thrsh_amp_eye_movement_edit.Value = '10';
            app.text20 = uilabel(app.uipanel19);
            app.text20.Text = 'Time Eye Mov (data pts)';
            app.Time_window_eye_movements_datapts_edit = uieditfield(app.uipanel19, 'text');
            app.Time_window_eye_movements_datapts_edit.Value = '25';
            app.text32 = uilabel(app.uipanel19);
            app.text32.Text = 'Artifact rejection';
            app.Art_rej_thrsh_edit = uieditfield(app.uipanel19, 'text');
            app.Art_rej_thrsh_edit.Value = '125';
            app.uipanel24 = uibuttongroup(app.uipanel17);
            app.uipanel24.Title = 'Analyze Response Free Stimuli';
            app.stimStandard_button = uiradiobutton(app.uipanel24);
            app.stimStandard_button.Text = 'Yes';
            app.stimStandard_button.Value = true;
            app.stimTarget_button = uiradiobutton(app.uipanel24);
            app.stimTarget_button.Text = 'No';
            app.uipanel4 = uibuttongroup(app.uipanel14);
            app.uipanel4.Title = 'Bandpass Raw Data';
            app.lowPass_filter_button = uieditfield(app.uipanel4, 'text');
            app.lowPass_filter_button.Value = '0.05';
            app.highPass_filter_button = uieditfield(app.uipanel4, 'text');
            app.highPass_filter_button.Value = '110';
            app.bandPass_on_button = uiradiobutton(app.uipanel4);
            app.bandPass_on_button.Text = 'BandPass ON';
            app.bandPass_off_button = uiradiobutton(app.uipanel4);
            app.bandPass_off_button.Text = 'BandPass OFF';
            app.bandPass_off_button.Value = true;
            app.text22 = uilabel(app.uipanel4);
            app.text22.Text = 'Low Pass (Hz)';
            app.text23 = uilabel(app.uipanel4);
            app.text23.Text = 'High Pass (Hz)';
            app.uipanel18 = uipanel(app.figure1);
            app.uipanel18.Title = 'Electrode Parameters & Processing';
            app.uipanel6 = uibuttongroup(app.uipanel18);
            app.uipanel6.Title = 'Re-Reference Data';
            app.Rereference_Single_Channel = uiradiobutton(app.uipanel6);
            app.Rereference_Single_Channel.Text = 'Re-Reference to Single Channel:';
            app.No_ReReference = uiradiobutton(app.uipanel6);
            app.No_ReReference.Text = 'No Re-Reference';
            app.No_ReReference.Value = true;
            app.ReReference_to_average = uiradiobutton(app.uipanel6);
            app.ReReference_to_average.Text = 'Re-Reference to Average of All Channels';
            app.singleCh_ref_edit = uieditfield(app.uipanel6, 'text');
            app.singleCh_ref_edit.Value = '71';
            app.uipanel1 = uibuttongroup(app.uipanel18);
            app.uipanel1.Title = 'Interpolation Method';
            app.noInterp_edit = uiradiobutton(app.uipanel1);
            app.noInterp_edit.Text = 'No Interpolation';
            app.noInterp_edit.Value = true;
            app.choose_spline_edit = uiradiobutton(app.uipanel1);
            app.choose_spline_edit.Text = 'Spline Interpolation';
            app.uipanel8 = uibuttongroup(app.uipanel1);
            app.uipanel8.Title = 'Load Bad Channel List';
            app.YesloadBD_CH_List = uiradiobutton(app.uipanel8);
            app.YesloadBD_CH_List.Text = 'Yes';
            app.NoloadBD_CH_List = uiradiobutton(app.uipanel8);
            app.NoloadBD_CH_List.Text = 'No';
            app.NoloadBD_CH_List.Value = true;
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'Std Dev Threshold for interpolation';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = '%Pts to remove from block (spline only)';
            app.choose_ave_edit = uiradiobutton(app.uipanel1);
            app.choose_ave_edit.Text = 'Mean Average';
            app.choose_weigted_ave_edit = uiradiobutton(app.uipanel1);
            app.choose_weigted_ave_edit.Text = 'Weighted Average';
            app.Pctge_pts_to_remove_edit = uieditfield(app.uipanel1, 'text');
            app.Pctge_pts_to_remove_edit.Value = '25';
            app.StD_thresh_for_interp_edit = uieditfield(app.uipanel1, 'text');
            app.StD_thresh_for_interp_edit.Value = '2.5';
        end
    end

    methods (Access = public)

        % Construct app
        function app = timeFreq_GUI_new_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @timeFreq_GUI_new_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end